classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        PushTool2            matlab.ui.container.toolbar.PushTool
        CheckBox_2           matlab.ui.control.CheckBox
        Button_8             matlab.ui.control.Button
        EditField_19         matlab.ui.control.EditField
        Label_39             matlab.ui.control.Label
        Button_7             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        DropDown_5           matlab.ui.control.DropDown
        Button_6             matlab.ui.control.Button
        Label_32             matlab.ui.control.Label
        DropDown_3           matlab.ui.control.DropDown
        Panel_5              matlab.ui.container.Panel
        Label_25             matlab.ui.control.Label
        Label_24             matlab.ui.control.Label
        EditField_15         matlab.ui.control.NumericEditField
        Label_23             matlab.ui.control.Label
        EditField_14         matlab.ui.control.NumericEditField
        Label_22             matlab.ui.control.Label
        EditField_13         matlab.ui.control.NumericEditField
        Label_21             matlab.ui.control.Label
        Spinner              matlab.ui.control.Spinner
        Label_20             matlab.ui.control.Label
        Label_15             matlab.ui.control.Label
        Label_14             matlab.ui.control.Label
        Label_13             matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Panel                matlab.ui.container.Panel
        Image2               matlab.ui.control.Image
        Spinner_3            matlab.ui.control.Spinner
        Label_34             matlab.ui.control.Label
        Spinner_2            matlab.ui.control.Spinner
        Label_33             matlab.ui.control.Label
        Label_10             matlab.ui.control.Label
        Label_9              matlab.ui.control.Label
        CheckBox             matlab.ui.control.CheckBox
        LEditField           matlab.ui.control.NumericEditField
        LEditFieldLabel      matlab.ui.control.Label
        REditField           matlab.ui.control.NumericEditField
        REditFieldLabel      matlab.ui.control.Label
        Panel_6              matlab.ui.container.Panel
        Label_36             matlab.ui.control.Label
        Label_35             matlab.ui.control.Label
        Label_19             matlab.ui.control.Label
        Label_18             matlab.ui.control.Label
        Label_17             matlab.ui.control.Label
        Label_16             matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        EditField_10         matlab.ui.control.NumericEditField
        EditField_9          matlab.ui.control.NumericEditField
        EditField_8          matlab.ui.control.NumericEditField
        EditField_7          matlab.ui.control.NumericEditField
        EditField_6          matlab.ui.control.NumericEditField
        EditField_5          matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        ZSpinner             matlab.ui.control.Spinner
        ZLabel               matlab.ui.control.Label
        YSpinner             matlab.ui.control.Spinner
        YSpinnerLabel        matlab.ui.control.Label
        XSpinner             matlab.ui.control.Spinner
        XSpinnerLabel        matlab.ui.control.Label
        Button_3             matlab.ui.control.Button
        Panel_4              matlab.ui.container.Panel
        sup2supLabel         matlab.ui.control.Label
        Label_30             matlab.ui.control.Label
        EditField_17         matlab.ui.control.NumericEditField
        Label_27             matlab.ui.control.Label
        EditField_16         matlab.ui.control.NumericEditField
        Label_26             matlab.ui.control.Label
        BetaEditField        matlab.ui.control.NumericEditField
        BetaEditFieldLabel   matlab.ui.control.Label
        AlpfaEditField       matlab.ui.control.NumericEditField
        AlpfaEditFieldLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
        Panel_2              matlab.ui.container.Panel
        Label_38             matlab.ui.control.Label
        EditField_18         matlab.ui.control.NumericEditField
        Label_37             matlab.ui.control.Label
        DropDown_4           matlab.ui.control.DropDown
        Label_31             matlab.ui.control.Label
        Label_12             matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        Label_11             matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        EditField_11         matlab.ui.control.NumericEditField
        Label_7              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Label_6              matlab.ui.control.Label
        Button               matlab.ui.control.Button
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear I
            global R nfi L nL N axial nx ny nz K lx ly lz CenterROI Nc zeror er eb G Grad level plane Levels alpfa betta ro t enb Boff div path last_path sw
            
            enb = 0;
            R = 0.0665; % Радиус катушки
            nfi = 15;  % Количество точек окружности
            
            L = 0.15; % Половина длины катушки (от 0 в одну сторону)
            nL = 11;  % Количество точек по длинне
            
            N = nfi*nL;
            axial = 0;
            
            nx = 7;    % Кол-во точек вдоль Х
            ny = 7;    % Кол-во точек вдоль Y
            nz = 7;    % Кол-во точек вдоль Z
            K = nx*ny*nz;  % Полное число точек ROI
            
            lx = 0.066;  % Длина области вдоль Х
            ly = 0.066;  % Длина области вдоль Y
            lz = 0.066;  % Длина области вдоль Z
            
            Levels = linspace(-lz/2, lz/2, nz)*1000;
                       
            CenterROI = [0 0 0]; % Положение центра ROI
            
            Nc = 5;        % Количество витков

            zeror = 0.0;
            er = 0.005;    % Отклонение от уровня
            eb = 0.005;    % Отклонение от уровня
            
            G = 0.01;   % Градиент
            Grad = 1;   % 1) X; 2) Y; 3) Z; 4) XY; 5) XZ; 6) YZ
            level = Levels(1)/1000;  % Положение плоскости
            plane = 0;  % Выбор плоскости 0)XY, 1)XZ, 2)YZ
           
            alpfa = 2500;     % Вклад индукции
            betta = 0.001;  % Вклад мощности
            ro = 0.017; % Удельное сопротивление (Ом*мм^2/м)
            t = 2;      % Тольщина слоя (мм) 
            
            Boff = 0;
            
            div = 0;
            
            path = pwd;
            last_path = path;
            sw = 0;
            
            app.EditField_19.Value = strcat(path,'\');
            app.REditField.Value = R*1000;
            app.LEditField.Value = L*1000;
            app.Spinner_3.Value = nL;
            app.Spinner_2.Value = nfi;
            app.CheckBox.Value = axial;
            app.XSpinner.Value = nx;
            app.YSpinner.Value = ny;
            app.ZSpinner.Value = nz;
            app.EditField_5.Value = lx*1000;
            app.EditField_6.Value = ly*1000;
            app.EditField_7.Value = lz*1000;
            app.EditField_8.Value = CenterROI(1)*1000;
            app.EditField_9.Value = CenterROI(2)*1000;
            app.EditField_10.Value = CenterROI(3)*1000;
            app.Spinner.Value = Nc;
            app.EditField_13.Value = zeror;
            app.EditField_14.Value = er*100;
            app.EditField_15.Value = eb*100;
            app.EditField_11.Value = G*1000;
            app.DropDown.Value = "X";
            app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
            app.AlpfaEditField.Value = alpfa/100;
            app.BetaEditField.Value = betta*10000;
            app.EditField_16.Value = ro;
            app.EditField_17.Value = t;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global R nfi L nL N axial nx ny nz lx ly lz CenterROI Node S ROI
            N = nfi*nL; % Количество узлов
            Node = CreateNode(R, L, nfi, nL, axial); % Создание массива узлов
            S = CreateS(N, nfi); % Создание массива элементов
            ROI = CreateROI(nx, ny, nz, lx, ly, lz, CenterROI); % Создание массива ROI
            app.UIAxes.Visible = "on";
            cla(app.UIAxes)
            plot3(app.UIAxes,Node(:,1)*1000,Node(:,2)*1000,Node(:,3)*1000,'b.'); % Отрисовка узлов
            hold(app.UIAxes, "on")
            xlabel(app.UIAxes, 'x, мм'), ylabel(app.UIAxes, 'y, мм'), zlabel(app.UIAxes,'z, мм')
            axis(app.UIAxes,"equal")
            grid(app.UIAxes, "on") 
            for i = 1:length(S(:,1))
                plot3(app.UIAxes,Node(S(i,:),1)*1000,Node(S(i,:),2)*1000,Node(S(i,:),3)*1000,'r-','LineWidth',0.01); % Отрисовка сетки
            end
            plot3(app.UIAxes,ROI(:,1)*1000,ROI(:,2)*1000,ROI(:,3)*1000,'k.'); % Отрисовка ROI
            
            app.Button_4.Enable = "on"; 
            app.Label_39.Visible = "on";
            app.Label_39.Text = strcat("Узлов: ",num2str(N),"          Элементов: ",num2str(length(S(:,1))));
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            global R
            R = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            global L
            L = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            global axial
            axial = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            global nx lx plane Levels level
            nx = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 2
                Levels = linspace(-lx/2, lx/2, nx)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            global ny ly plane Levels level
            ny = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 1
                Levels = linspace(-ly/2, ly/2, ny)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            global nz lz Levels plane level
            nz = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 0
                Levels = linspace(-lz/2, lz/2, nz)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            global lx nx Levels plane level
            lx = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 2
                Levels = linspace(-lx/2, lx/2, nx)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            global ly ny Levels plane level
            ly = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 1
                Levels = linspace(-ly/2, ly/2, ny)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            global lz nz Levels plane level
            lz = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            if plane == 0
                Levels = linspace(-lz/2, lz/2, nz)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
            end
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            global CenterROI
            CenterROI(1) = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            value = app.EditField_9.Value;
            global CenterROI
            CenterROI(2) = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            global CenterROI
            CenterROI(3) = value/1000;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            global ROI lx ly lz nx ny nz Bdes X Y Z B plane level G Grad I Bz X1 Y1 Z1 N S Node enb Boff DES div DES1
            
            switch Grad
                case 1
                    Bdes = G*ROI(:,1) + Boff;
                case 2
                    Bdes = G*ROI(:,2) + Boff;
                case 3
                    Bdes = G*ROI(:,3) + Boff;
                case 4
                    Bdes = G*ROI(:,1).*ROI(:,2) + Boff;
                case 5
                    Bdes = G*ROI(:,1).*ROI(:,3) + Boff;
                case 6
                    Bdes = G*ROI(:,2).*ROI(:,3) + Boff;
                case 7
                    Bdes = G*(ROI(:,1).*ROI(:,1)-ROI(:,2).*ROI(:,2)) + Boff;
            end
            
            [X,Y,Z,B] = Transform3D(lx, ly, lz, nx, ny, nz, Bdes); % Для отрисовки поля
            
            app.UIAxes_3.Visible = "on";
            cla(app.UIAxes_3)
            if (plane == 0) % ---------------------------------------------------------- XY
                k = find(abs(Z(1,1,:)-level) < 0.00001);
                contourf(app.UIAxes_3,X(:,:,k)*1000, Y(:,:,k)*1000, B(:,:,k)*1000)
                xlabel(app.UIAxes_3,'x, мм'), ylabel(app.UIAxes_3,'y, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, z = ', num2str(level*1000), 'мм')) 
            elseif (plane == 1) % ------------------------------------------------------ XZ 
                k = find(abs(Y(1,:,1)-level) < 0.00001);
                XX = reshape(X(:,k,:),[nx,nz,1]);
                ZZ = reshape(Z(:,k,:),[nx,nz,1]);
                BB = reshape(B(:,k,:),[nx,nz,1]);
                contourf(app.UIAxes_3,XX*1000, ZZ*1000, BB*1000)
                xlabel(app.UIAxes_3,'x, мм'), ylabel(app.UIAxes_3,'z, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, y = ', num2str(level*1000), 'мм'))
            elseif (plane == 2) % ------------------------------------------------------ YZ
                k = find(abs(X(:,1,1)-level) < 0.00001);
                YY = reshape(Y(k,:,:),[ny,nz,1]);
                ZZ = reshape(Z(k,:,:),[ny,nz,1]);
                BB = reshape(B(k,:,:),[ny,nz,1]);
                contourf(app.UIAxes_3,YY*1000, ZZ*1000, BB*1000)
                xlabel(app.UIAxes_3,'y, мм'), ylabel(app.UIAxes_3,'z, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, x = ', num2str(level*1000), 'мм'))
            else
                disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
            end
            colorbar(app.UIAxes_3)
            box(app.UIAxes_3,"on")
            grid(app.UIAxes_3,"on") 
            
            if enb
                
                [Bz, DES] = ReceivedField(I, nx*ny*nz, N, S, Node, ROI, Bdes);
                Krit = max(max(max(abs(DES)./max(max(max(abs(Bdes)))))))*100;
                [X1,Y1,Z1,Bz] = Transform3D(lx, ly, lz, nx, ny, nz, Bz);
                [X1,Y1,Z1,DES1] = Transform3D(lx, ly, lz, nx, ny, nz, DES);
                DES1 = 100*DES1./max(max(max(Bdes)));
                
                if ~div
                    cla(app.UIAxes_4, 'reset')
                    if (plane == 0) % ---------------------------------------------------------- XY
                        k = find(abs(Z1(1,1,:)-level) < 0.00001);
                        contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, Bz(:,:,k)*1000)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, z = ', num2str(level*1000), 'мм')) 
                    elseif (plane == 1) % ------------------------------------------------------ XZ
                        k = find(abs(Y1(1,:,1)-level) < 0.00001);
                        XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                        ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                        BB1 = reshape(Bz(:,k,:),[nx,nz,1]);
                        contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1*1000)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, y = ', num2str(level*1000), 'мм'))
                    elseif (plane == 2) % ------------------------------------------------------ YZ
                        k = find(abs(X1(:,1,1)-level) < 0.00001);
                        YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                        ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                        BB1 = reshape(Bz(k,:,:),[ny,nz,1]);
                        contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1*1000)
                        xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, x = ', num2str(level*1000), 'мм'))
                    else
                        disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                    end
                    colorbar(app.UIAxes_4)
                    box(app.UIAxes_4,"on")
                    grid(app.UIAxes_4,"on")
                else
                    cla(app.UIAxes_4, 'reset')
                    if (plane == 0) % ---------------------------------------------------------- XY
                        k = find(abs(Z1(1,1,:)-level) < 0.00001);
                        contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, DES1(:,:,k))
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Отклонение, %, z = ', num2str(level*1000), 'мм')) 
                    elseif (plane == 1) % ------------------------------------------------------ XZ
                        k = find(abs(Y1(1,:,1)-level) < 0.00001);
                        XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                        ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                        BB1 = reshape(DES1(:,k,:),[nx,nz,1]);
                        contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, y = ', num2str(level*1000), 'мм'))
                    elseif (plane == 2) % ------------------------------------------------------ YZ
                        k = find(abs(X1(:,1,1)-level) < 0.00001);
                        YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                        ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                        BB1 = reshape(DES1(k,:,:),[ny,nz,1]);
                        contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1)
                        xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, x = ', num2str(level*1000), 'мм'))
                    else
                        disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                    end
                    colorbar(app.UIAxes_4)
                    box(app.UIAxes_4,"on")
                    grid(app.UIAxes_4,"on")
                end
                    
                app.Label_32.Text = strcat("Максимальное отклонение: ", num2str(Krit), ' %');
            else
                app.Button_3.Enable = "on";
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            global G
            G = value/1000;
            app.Button_3.Enable = "off";
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            global Grad
            switch value
                case "X"
                    Grad = 1;
                    app.Label_8.Text = "мТ/м";
                case "Y"
                    Grad = 2;
                    app.Label_8.Text = "мТ/м";
                case "Z"
                    Grad = 3;
                    app.Label_8.Text = "мТ/м";
                case "XY"
                    Grad = 4;
                    app.Label_8.Text = "мТ/м<sup>2</sup>";
                case "XZ"
                    Grad = 5;
                    app.Label_8.Text = "мТ/м<sup>2</sup>";
                case "YZ"
                    Grad = 6;
                    app.Label_8.Text = "мТ/м<sup>2</sup>";
                case "X2 - Y2"
                    Grad = 7;
                    app.Label_8.Text = "мТ/м<sup>2</sup>";
            end
            app.Button_3.Enable = "off";
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            global plane nx ny nz lx ly lz Levels
            switch value
                case "XY"
                    plane = 0;
                    Levels = linspace(-lz/2, lz/2, nz)*1000;
                    app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                case "XZ"
                    plane = 1;
                    Levels = linspace(-ly/2, ly/2, ny)*1000;
                    app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                case "YZ"
                    plane = 2;
                    Levels = linspace(-lx/2, lx/2, nx)*1000;
                    app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                    
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            global level
            level = str2double(convertCharsToStrings(value))/1000;
        end

        % Value changed function: AlpfaEditField
        function AlpfaEditFieldValueChanged(app, event)
            value = app.AlpfaEditField.Value;
            global alpfa 
            alpfa = value*100;
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            value = app.BetaEditField.Value;
            global betta
            betta = value/10000;
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            value = app.EditField_16.Value;
            global ro
            ro = value;
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            value = app.EditField_17.Value;
            global t
            t = value;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            global Nc
            Nc = value;
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            value = app.EditField_14.Value;
            global er
            er = value/100;
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            value = app.EditField_15.Value;
            global eb
            eb = value/100;
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            value = app.EditField_13.Value;
            global zeror
            zeror = value;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global N Node S ROI ro t alpfa betta Bdes I II Fi Ll L nfi nL px py Fi_int L_int I_int Red Blue Red1 Blue1 Red2 Blue2 R Nc eb er zeror Bz nx ny nz DES 
            global level plane lx ly lz X1 Y1 Z1 enb div DES1 mode axial
            
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_13.Text = "Идет расчет...";
            app.Button.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            app.Panel.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.DropDown.Enable = "off";
            app.EditField_11.Enable = "off";
            app.EditField_18.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Button_8.Enable = "off";
            app.Button_7.Enable = "off";
            app.EditField_19.Enable = "off";
            app.CheckBox_2.Enable = "off";
            
                       
            tic  
            pause(1)
            I = ABI(L, R, lx, ly, lz, N, Node, S, ROI, ro, t, alpfa, betta, Bdes);
            [Fi, Ll, II] = Transform2D(L, nfi, nL, I);
            [px,py] = gradient(II);
            p=max(max(sqrt(px.^2+py.^2)));
            px = px/(p);
            py = py/(p);
         
            [Bz, DES] = ReceivedField(I, nx*ny*nz, N, S, Node, ROI, Bdes);
            Krit = max(max(max(abs(DES)./max(max(max(Bdes))))))*100;
            
            [Fi_int, L_int, I_int, Red, Blue, Red1, Blue1, Red2, Blue2] = InterpCircuit(R, L, nfi, nL, I, Nc, eb, er, zeror);
            W = 2*pi*R*1000;
            LL = L*1000;
            cla(app.UIAxes_2, 'reset')
            switch app.DropDown_3.Value
                case "Потоковая функция"
                    
                    contourf(app.UIAxes_2,Fi,Ll*1000,II);
                    %box(app.UIAxes_2,"on")
                    xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L, мм')
                    colorbar(app.UIAxes_2)
                    
                case "Потоковая функция + Grad"
                    
                    app.Button_7.Enable = "on";
                    contourf(app.UIAxes_2,Fi,Ll*1000,II);
                    %box(app.UIAxes_2,"on")
                    hold(app.UIAxes_2,"on")
                    quiver(app.UIAxes_2,Fi,Ll*1000,px,py,0.2,'*r', 'MarkerSize', 2, 'LineWidth', 0.5)
                    xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L, мм')
                    colorbar(app.UIAxes_2)
                    hold(app.UIAxes_2,"off")
                    
                case "Намотка 2D"
                    
                    app.Button_7.Enable = "on";
                    if ~isempty(Red2)
                        plot(app.UIAxes_2,Red2(:,2)*1000, Red2(:,1)*1000, '.r','MarkerSize',1)
                        hold(app.UIAxes_2,"on")
                    end
                    if ~isempty(Blue2)
                        plot(app.UIAxes_2,Blue2(:,2)*1000, Blue2(:,1)*1000, '.b','MarkerSize',1)
                    end
                    if axial
                        xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'z, мм')
                    else 
                        xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'x, мм')
                    end
                    rectangle(app.UIAxes_2,'Position', [-W/2 -LL W 2*LL])
                    axis(app.UIAxes_2,"equal")
                    grid(app.UIAxes_2,"on")
                    hold(app.UIAxes_2,"off")
                    
                case "Намотка 3D"
                    if axial  
                        if ~isempty(Red1)
                            Red1 = rotY(Red1, pi/2);
                        end
                        if ~isempty(Blue1)
                            Blue1 = rotY(Blue1, pi/2);
                        end
                    end
                    if ~isempty(Red1)
                        plot3(app.UIAxes_2, Red1(:,1)*1000, Red1(:,2)*1000, Red1(:,3)*1000, '.r','MarkerSize',1)
                        hold(app.UIAxes_2,"on")
                    end
                    if ~isempty(Blue1)
                        plot3(app.UIAxes_2,Blue1(:,1)*1000, Blue1(:,2)*1000, Blue1(:,3)*1000, '.b','MarkerSize',1)
                    end
                    %box(app.UIAxes_2,"on")
                    xlabel(app.UIAxes_2,'x, мм'), ylabel(app.UIAxes_2,'y, мм'), zlabel(app.UIAxes_2,'z, мм')
                    axis(app.UIAxes_2,"equal")
                    grid(app.UIAxes_2,"on")
                    hold(app.UIAxes_2,"off")
                    
            end
            
            [X1,Y1,Z1,Bz] = Transform3D(lx, ly, lz, nx, ny, nz, Bz);
            [X1,Y1,Z1,DES1] = Transform3D(lx, ly, lz, nx, ny, nz, DES);
            DES1 = 100*DES1./max(max(max(Bdes)));
            
            if ~div
                cla(app.UIAxes_4, 'reset')
                if (plane == 0) % ---------------------------------------------------------- XY
                    k = find(abs(Z1(1,1,:)-level) < 0.00001);
                    contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, Bz(:,:,k)*1000)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, z = ', num2str(level*1000), 'мм')) 
                elseif (plane == 1) % ------------------------------------------------------ XZ
                    k = find(abs(Y1(1,:,1)-level) < 0.00001);
                    XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                    ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                    BB1 = reshape(Bz(:,k,:),[nx,nz,1]);
                    contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1*1000)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, y = ', num2str(level*1000), 'мм'))
                elseif (plane == 2) % ------------------------------------------------------ YZ
                    k = find(abs(X1(:,1,1)-level) < 0.00001);
                    YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                    ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                    BB1 = reshape(Bz(k,:,:),[ny,nz,1]);
                    contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1*1000)
                    xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, x = ', num2str(level*1000), 'мм'))
                else
                    disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                end
                colorbar(app.UIAxes_4)
                box(app.UIAxes_4,"on")
                grid(app.UIAxes_4,"on")
            else
                cla(app.UIAxes_4, 'reset')
                if (plane == 0) % ---------------------------------------------------------- XY
                    k = find(abs(Z1(1,1,:)-level) < 0.00001);
                    contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, DES1(:,:,k))
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Отклонение, %, z = ', num2str(level*1000), 'мм')) 
                elseif (plane == 1) % ------------------------------------------------------ XZ
                    k = find(abs(Y1(1,:,1)-level) < 0.00001);
                    XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                    ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                    BB1 = reshape(DES1(:,k,:),[nx,nz,1]);
                    contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, y = ', num2str(level*1000), 'мм'))
                elseif (plane == 2) % ------------------------------------------------------ YZ
                    k = find(abs(X1(:,1,1)-level) < 0.00001);
                    YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                    ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                    BB1 = reshape(DES1(k,:,:),[ny,nz,1]);
                    contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1)
                    xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, x = ', num2str(level*1000), 'мм'))
                else
                    disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                end
                colorbar(app.UIAxes_4)
                box(app.UIAxes_4,"on")
                grid(app.UIAxes_4,"on")
            end
            
            
            Time = toc;
            
            enb = 1;
            
            app.DropDown_3.Visible = "on";
            app.DropDown_3.Enable = "on";
            app.UIAxes_2.Visible = "on";
            app.UIAxes_4.Visible = "on";
            app.Lamp.Color = [0.00,1.00,0.00];
            app.Label_15.Text = strcat(num2str(round(Time)), 'с');
            app.Label_13.Text = "Готово!";
            app.Label_32.Visible = "on";
            app.Label_32.Text = strcat("Максимальное отклонение: ", num2str(Krit), ' %');
            app.Button.Enable = "on";
            %app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            %app.Panel.Enable = "on";
            app.Panel_6.Enable = "on";
            app.Panel_2.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_6.Enable = "on";
            app.DropDown_5.Visible = "on";
            app.PushTool2.Enable = "on";
            app.PushTool.Enable = "on";
            app.PushTool2.Enable = "on";
            app.Button_8.Enable = "on";
            app.EditField_19.Enable = "on";
            app.CheckBox_2.Enable = "on";
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            global II Fi Ll px py Red1 Blue1 Red2 Blue2 mode R L axial
            
            value = app.DropDown_3.Value;
            cla(app.UIAxes_2)
            switch value
                case "Потоковая функция"
                    mode = 1;
                    app.Button_7.Enable = "off";
                    cla(app.UIAxes_2, 'reset')
                    contourf(app.UIAxes_2,Fi,Ll,II);
                    %box(app.UIAxes_2,"on")
                    xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L')
                    colorbar(app.UIAxes_2)
                    
                case "Потоковая функция + Grad"
                    mode = 2;
                    app.Button_7.Enable = "on";
                    cla(app.UIAxes_2, 'reset')
                    contourf(app.UIAxes_2,Fi,Ll,II);
                    %box(app.UIAxes_2,"on")
                    hold(app.UIAxes_2,"on")
                    quiver(app.UIAxes_2,Fi,Ll,px,py,0.2,'*r', 'MarkerSize', 2, 'LineWidth', 0.5)
                    xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L')
                    colorbar(app.UIAxes_2)
                    hold(app.UIAxes_2,"off")
                    
                case "Намотка 2D"
                    mode = 3;
                    app.Button_7.Enable = "on";
                    W = 2*pi*R*1000;
                    LL = L*1000;
                    cla(app.UIAxes_2, 'reset')
                    if ~isempty(Red2)
                        plot(app.UIAxes_2,Red2(:,2)*1000, Red2(:,1)*1000, '.r','MarkerSize',1)
                        hold(app.UIAxes_2,"on")
                    end
                    if ~isempty(Blue2)
                        plot(app.UIAxes_2,Blue2(:,2)*1000, Blue2(:,1)*1000, '.b','MarkerSize',1)
                    end
                    %box(app.UIAxes_2,"on")
                    rectangle(app.UIAxes_2,'Position', [-W/2 -LL W 2*LL])
                    if axial
                        xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'z, мм')
                    else 
                        xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'x, мм')
                    end
                    axis(app.UIAxes_2,"equal")
                    grid(app.UIAxes_2,"on") 
                    
                case "Намотка 3D"
                    mode = 4;
                    app.Button_7.Enable = "off";
                    cla(app.UIAxes_2, 'reset')
                    if axial  
                        if ~isempty(Red1)
                            Red1 = rotY(Red1, pi/2);
                        end
                        if ~isempty(Blue1)
                            Blue1 = rotY(Blue1, pi/2);
                        end
                    end
                    if ~isempty(Red1)
                        plot3(app.UIAxes_2, Red1(:,1)*1000, Red1(:,2)*1000, Red1(:,3)*1000, '.r','MarkerSize',1)
                        hold(app.UIAxes_2,"on")
                    end
                    if ~isempty(Blue1)
                        plot3(app.UIAxes_2,Blue1(:,1)*1000, Blue1(:,2)*1000, Blue1(:,3)*1000, '.b','MarkerSize',1)
                    end
                    %box(app.UIAxes_2,"on")
                    xlabel(app.UIAxes_2,'x, мм'), ylabel(app.UIAxes_2,'y, мм'), zlabel(app.UIAxes_2,'z, мм')
                    axis(app.UIAxes_2,"equal")
                    grid(app.UIAxes_2,"on")
                    
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            global mode I L nfi nL Fi_int L_int I_int Red Blue Red1 Blue1 Red2 Blue2 R Nc eb er zeror axial
            W = 2*pi*R*1000;
            LL = L*1000;
            if (mode == 3) || (mode == 4)
                [Fi_int, L_int, I_int, Red, Blue, Red1, Blue1, Red2, Blue2] = InterpCircuit(R, L, nfi, nL, I, Nc, eb, er, zeror);
                switch mode
                    case 3
                        
                        cla(app.UIAxes_2, 'reset')
                        
                        if ~isempty(Red2)
                            plot(app.UIAxes_2,Red2(:,2)*1000, Red2(:,1)*1000, '.r','MarkerSize',1)
                            hold(app.UIAxes_2,"on")
                        end
                        if ~isempty(Blue2)
                            plot(app.UIAxes_2,Blue2(:,2)*1000, Blue2(:,1)*1000, '.b','MarkerSize',1)
                        end
                        %box(app.UIAxes_2,"on")
                        rectangle(app.UIAxes_2,'Position', [-W/2 -LL W 2*LL])
                        if axial
                            xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'z, мм')
                        else 
                            xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'x, мм')
                        end
                        axis(app.UIAxes_2,"equal")
                        grid(app.UIAxes_2,"on") 
                        
                    case 4 
                        if axial  
                            if ~isempty(Red1)
                                Red1 = rotY(Red1, pi/2);
                            end
                            if ~isempty(Blue1)
                                Blue1 = rotY(Blue1, pi/2);
                            end
                        end
                        cla(app.UIAxes_2, 'reset')
                        if ~isempty(Red1)
                            plot3(app.UIAxes_2, Red1(:,1)*1000, Red1(:,2)*1000, Red1(:,3)*1000, '.r','MarkerSize',1)
                            hold(app.UIAxes_2,"on")
                        end
                        if ~isempty(Blue1)
                            plot3(app.UIAxes_2,Blue1(:,1)*1000, Blue1(:,2)*1000, Blue1(:,3)*1000, '.b','MarkerSize',1)
                        end
                        %box(app.UIAxes_2,"on")
                        xlabel(app.UIAxes_2,'x, мм'), ylabel(app.UIAxes_2,'y, мм'), zlabel(app.UIAxes_2,'z, мм')
                        axis(app.UIAxes_2,"equal")
                        grid(app.UIAxes_2,"on")
                        
                end
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            global enb
            cla(app.UIAxes_2, 'reset')
            cla(app.UIAxes_4, 'reset')
            app.UIAxes_2.Visible = "off";
            app.UIAxes_4.Visible = "off";
            clear I Bz
            pause(1)
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_4.Enable = "off";
            %app.Button.Enable = "on";
            app.Panel.Enable = "on";
            app.Button_3.Enable = "off";
            app.Button_5.Enable = "off";
            app.DropDown_3.Enable = "off";
            app.DropDown_3.Visible = "off";
            app.Label_15.Text = "0c";
            app.Label_32.Visible = "off";
            app.EditField_11.Enable = "on";
            app.DropDown.Enable = "on";
            app.EditField_18.Enable = "on";
            app.DropDown_5.Visible = "off";
            app.PushTool2.Enable = "off";
            app.PushTool.Enable = "on";
            enb = 0;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = app.Spinner_2.Value;
            global nfi
            nfi = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            value = app.Spinner_3.Value;
            global nL
            nL = value;
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            global Boff
            value = app.EditField_18.Value;
            Boff = value/1000;
            app.Button_3.Enable = "off";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            global Grad L R nL nfi alpfa betta path Nc sw mode
            
            app.Button.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_19.Enable = "off";
            app.Button_7.Enable = "off";
            app.CheckBox_2.Enable = "off";
            app.Button_6.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.DropDown_3.Enable = "off";
            app.DropDown_5.Enable = "off";
            
            switch mode
                case 2
                    st = "SFGrad_";
                case 3
                    st = "Circuit_";   
            end
            
            switch Grad 
                case 1
                    str = "X";
                case 2
                    str = "Y";
                case 3
                    str = "Z";
                case 4
                    str = "XY";
                case 5 
                    str = "XZ";
                case 6
                    str = "YZ";
                case 7
                    str = "X2mY2";
            end
            str = strcat(st, str,"_",num2str(L*1000),"_",num2str(R*1000),"_",num2str(nfi),"_",num2str(nL),"_",num2str(alpfa/100),"_",num2str(betta*10000),"_",num2str(Nc),"_",num2str(sw),".png");
            str = strcat(path,str);
            delete(str);
            
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_13.Text = "Сохранение...";
            
            refreshdata(app.UIAxes_2)
            if ~sw
                set(app.UIAxes_2,'Visible', 'off')
                colorbar(app.UIAxes_2, "off")
            end
            
            pause(1)
            exportgraphics(app.UIAxes_2,str,'Resolution',1500)
            set(app.UIAxes_2,'Visible', 'on')
            if mode == 2
                colorbar(app.UIAxes_2)
            end
            app.Lamp.Color = [0.00,1.00,0.00];
            app.Label_13.Text = "Готово!";
            
            app.Button.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_6.Enable = "on";
            app.Panel_2.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            app.Button_8.Enable = "on";
            app.EditField_19.Enable = "on";
            app.Button_7.Enable = "on";
            app.CheckBox_2.Enable = "on";
            app.Button_6.Enable = "on";
            app.PushTool.Enable = "on";
            app.PushTool2.Enable = "on";
            app.DropDown_3.Enable = "on";
            app.DropDown_5.Enable = "on";

        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            global div plane level X1 Y1 Z1 Bz DES1 nx ny nz
            switch value
                case "Полученное поле"
                    div = 0;
                case "Разность"
                    div = 1;
            end
            if ~div
                cla(app.UIAxes_4, 'reset')
                if (plane == 0) % ---------------------------------------------------------- XY
                    k = find(abs(Z1(1,1,:)-level) < 0.00001);
                    surf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, Bz(:,:,k)*1000)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, z = ', num2str(level*1000), 'мм')) 
                elseif (plane == 1) % ------------------------------------------------------ XZ
                    k = find(abs(Y1(1,:,1)-level) < 0.00001);
                    XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                    ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                    BB1 = reshape(Bz(:,k,:),[nx,nz,1]);
                    surf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1*1000)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, y = ', num2str(level*1000), 'мм'))
                elseif (plane == 2) % ------------------------------------------------------ YZ
                    k = find(abs(X1(:,1,1)-level) < 0.00001);
                    YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                    ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                    BB1 = reshape(Bz(k,:,:),[ny,nz,1]);
                    surf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1*1000)
                    xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, x = ', num2str(level*1000), 'мм'))
                else
                    disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                end
                colorbar(app.UIAxes_4)
                box(app.UIAxes_4,"on")
                grid(app.UIAxes_4,"on")
            else
                cla(app.UIAxes_4, 'reset')
                if (plane == 0) % ---------------------------------------------------------- XY
                    k = find(abs(Z1(1,1,:)-level) < 0.00001);
                    surf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, DES1(:,:,k))
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Отклонение, %, z = ', num2str(level*1000), 'мм')) 
                elseif (plane == 1) % ------------------------------------------------------ XZ
                    k = find(abs(Y1(1,:,1)-level) < 0.00001);
                    XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                    ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                    BB1 = reshape(DES1(:,k,:),[nx,nz,1]);
                    surf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1)
                    xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, y = ', num2str(level*1000), 'мм'))
                elseif (plane == 2) % ------------------------------------------------------ YZ
                    k = find(abs(X1(:,1,1)-level) < 0.00001);
                    YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                    ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                    BB1 = reshape(DES1(k,:,:),[ny,nz,1]);
                    surf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1)
                    xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, x = ', num2str(level*1000), 'мм'))
                else
                    disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                end
                colorbar(app.UIAxes_4)
                box(app.UIAxes_4,"on")
                grid(app.UIAxes_4,"on")
            end
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            global path last_path
            path = uigetdir;
            if path == 0
                path = last_path;
            else 
                last_path = path;
            end
            if path(length(path)) ~= '\'
                path = strcat(path,'\');
            end
            app.EditField_19.Value = path;
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            global Grad L R nL nfi alpfa betta last_path R L nL nfi G I axial lx ly lz nx ny nz Boff CenterROI path
            
            switch axial
                case true
                    a = 1;
                case false
                    a = 0;
            end
            cx = CenterROI(1);
            cy = CenterROI(2);
            cz = CenterROI(3);
            
            path = uigetdir;
            if path == 0
                path = last_path;
            else 
                last_path = path;
            end
            if path(length(path)) ~= '\'
                path = strcat(path,'\');
            end
            
            switch Grad
                case 1
                    str = "Out_I_X";
                case 2
                    str = "Out_I_Y";
                case 3
                    str = "Out_I_Z";
                case 4
                    str = "Out_I_XY";
                case 5 
                    str = "Out_I_XZ";
                case 6
                    str = "Out_I_YZ";
                case 7
                    str = "Out_I_X2mY2";
            end
            
            str = strcat(str,"_",num2str(L*1000),"_",num2str(R*1000),"_",num2str(nfi),"_",num2str(nL),"_",num2str(axial),"_",num2str(alpfa/100),"_",num2str(betta*10000),".txt");
            str = strcat(path,str);
            delete(str)
            save(str,"L","R","nfi","nL","a","lx","ly","lz","nx","ny","nz","Boff","cx","cy","cz","Grad","G","I","-ascii")
            pause(1)
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            global Grad L R nL nfi R L nL nfi G I axial lx ly lz nx ny nz Boff CenterROI Levels enb Node S ROI N div plane level
            
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_13.Text = "Загрузка...";
            app.PushTool.Enable = "off";
            app.Panel.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_19.Enable = "off";
            app.Button_5.Enable = "off";
            app.CheckBox_2.Enable = "off";
            app.Button_7.Enable = "off";
            app.DropDown.Enable = "off";
            app.EditField_11.Enable = "off";
            app.EditField_18.Enable = "off";
            app.DropDown_3.Enable = "off";
            app.DropDown_5.Enable = "off";
            
            [file,path3] = uigetfile('*.txt');
            path3 = strcat(path3,file);
            fileID = fopen(path3,'r');
            
            if fileID ~= -1

                data = fscanf(fileID,'%f');
                
                L = data(1);
                R = data(2);
                nfi = data(3);
                nL = data(4);
                axial = data(5);
                lx = data(6);
                ly = data(7);
                lz = data(8);
                nx = data(9);
                ny = data(10);
                nz = data(11);
                Boff = data(12);
                CenterROI(1) = data(13);
                CenterROI(2) = data(14);
                CenterROI(3) = data(15);
                Grad = data(16);
                G = data(17);
                I = data(18:length(data(:)));
                
                plane = 0;
                Levels = linspace(-lz/2, lz/2, nz)*1000;
                app.DropDown_4.Items = strtrim(cellstr(num2str(Levels'))');
                level = Levels(1)/1000;
                app.DropDown_4.Value = num2str(level*1000);
                N = nfi*nL;
                div = 0;
                
                W = 2*pi*R*1000;
                LL = L*1000;
                
                app.REditField.Value = R*1000;
                app.LEditField.Value = L*1000;
                app.Spinner_3.Value = nL;
                app.Spinner_2.Value = nfi;
                switch axial 
                    case 1
                        app.CheckBox.Value = true;
                    case 0
                        app.CheckBox.Value = false;
                end
                
                app.XSpinner.Value = nx;
                app.YSpinner.Value = ny;
                app.ZSpinner.Value = nz;
                app.EditField_5.Value = lx*1000;
                app.EditField_6.Value = ly*1000;
                app.EditField_7.Value = lz*1000;
                app.EditField_8.Value = CenterROI(1)*1000;
                app.EditField_9.Value = CenterROI(2)*1000;
                app.EditField_10.Value = CenterROI(3)*1000;
                app.EditField_11.Value = G*1000;
                app.EditField_18.Value = Boff*1000;
                app.DropDown_5.Value = "Полученное поле";
                app.DropDown_2.Value = "XY";
                
                switch Grad
                    case 1
                        app.DropDown.Value = "X";
                        app.Label_8.Text = "мТ/м";
                    case 2
                        app.DropDown.Value = "Y";
                        app.Label_8.Text = "мТ/м";
                    case 3
                        app.DropDown.Value = "Z";
                        app.Label_8.Text = "мТ/м";
                    case 4
                        app.DropDown.Value = "XY";
                        app.Label_8.Text = "мТ/м<sup>2</sup>";
                    case 5 
                        app.DropDown.Value = "XZ";
                        app.Label_8.Text = "мТ/м<sup>2</sup>";
                    case 6
                        app.DropDown.Value = "YZ";
                        app.Label_8.Text = "мТ/м<sup>2</sup>";
                    case 7
                        app.DropDown.Value = "X2 - Y2";
                        app.Label_8.Text = "мТ/м<sup>2</sup>";
                end
                
                enb = 1;
                
                % -------------------------------------------------------------------------------------------------------- Сетка
                
                Node = CreateNode(R, L, nfi, nL, axial); % Создание массива узлов
                S = CreateS(N, nfi); % Создание массива элементов
                ROI = CreateROI(nx, ny, nz, lx, ly, lz, CenterROI); % Создание массива ROI
                
                app.UIAxes.Visible = "on";
                cla(app.UIAxes)
                plot3(app.UIAxes,Node(:,1)*1000,Node(:,2)*1000,Node(:,3)*1000,'b.'); % Отрисовка узлов
                hold(app.UIAxes, "on")
                xlabel(app.UIAxes, 'x, мм'), ylabel(app.UIAxes, 'y, мм'), zlabel(app.UIAxes,'z, мм')
                axis(app.UIAxes,"equal")
                grid(app.UIAxes, "on") 
                for i = 1:length(S(:,1))
                    plot3(app.UIAxes,Node(S(i,:),1)*1000,Node(S(i,:),2)*1000,Node(S(i,:),3)*1000,'r-','LineWidth',0.01); % Отрисовка сетки
                end
                plot3(app.UIAxes,ROI(:,1)*1000,ROI(:,2)*1000,ROI(:,3)*1000,'k.'); % Отрисовка ROI
                
                app.Button_4.Enable = "on"; 
                app.Label_39.Visible = "on";
                app.Label_39.Text = strcat("Узлов: ",num2str(N),"          Элементов: ",num2str(length(S(:,1))));
                
                % -------------------------------------------------------------------------------------------------------- Поле
                global Bdes X Y Z B Bz X1 Y1 Z1 DES DES1
                switch Grad
                    case 1
                        Bdes = G*ROI(:,1) + Boff;
                    case 2
                        Bdes = G*ROI(:,2) + Boff;
                    case 3
                        Bdes = G*ROI(:,3) + Boff;
                    case 4
                        Bdes = G*ROI(:,1).*ROI(:,2) + Boff;
                    case 5
                        Bdes = G*ROI(:,1).*ROI(:,3) + Boff;
                    case 6
                        Bdes = G*ROI(:,2).*ROI(:,3) + Boff;
                    case 7
                        Bdes = G*(ROI(:,1).*ROI(:,1)-ROI(:,2).*ROI(:,2)) + Boff;
                end
                
                [X,Y,Z,B] = Transform3D(lx, ly, lz, nx, ny, nz, Bdes); % Для отрисовки поля
                
                app.UIAxes_3.Visible = "on";
                cla(app.UIAxes_3)
                if (plane == 0) % ---------------------------------------------------------- XY
                    k = find(abs(Z(1,1,:)-level) < 0.00001);
                    contourf(app.UIAxes_3,X(:,:,k)*1000, Y(:,:,k)*1000, B(:,:,k)*1000)
                    xlabel(app.UIAxes_3,'x, мм'), ylabel(app.UIAxes_3,'y, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, z = ', num2str(level*1000), 'мм')) 
                elseif (plane == 1) % ------------------------------------------------------ XZ 
                    k = find(abs(Y(1,:,1)-level) < 0.00001);
                    XX = reshape(X(:,k,:),[nx,nz,1]);
                    ZZ = reshape(Z(:,k,:),[nx,nz,1]);
                    BB = reshape(B(:,k,:),[nx,nz,1]);
                    contourf(app.UIAxes_3,XX*1000, ZZ*1000, BB*1000)
                    xlabel(app.UIAxes_3,'x, мм'), ylabel(app.UIAxes_3,'z, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, y = ', num2str(level*1000), 'мм'))
                elseif (plane == 2) % ------------------------------------------------------ YZ
                    k = find(abs(X(:,1,1)-level) < 0.00001);
                    YY = reshape(Y(k,:,:),[ny,nz,1]);
                    ZZ = reshape(Z(k,:,:),[ny,nz,1]);
                    BB = reshape(B(k,:,:),[ny,nz,1]);
                    contourf(app.UIAxes_3,YY*1000, ZZ*1000, BB*1000)
                    xlabel(app.UIAxes_3,'y, мм'), ylabel(app.UIAxes_3,'z, мм'), title(app.UIAxes_3,strcat('Целевое поле, мТ, x = ', num2str(level*1000), 'мм'))
                else
                    disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                end
                colorbar(app.UIAxes_3)
                box(app.UIAxes_3,"on")
                grid(app.UIAxes_3,"on") 
                
                % -------------------------------------------------------------------------------------------------------- I
                
                global II Fi Ll px py Fi_int L_int I_int Red Blue Red1 Blue1 Red2 Blue2 R Nc eb er zeror 
                
                [Fi, Ll, II] = Transform2D(L, nfi, nL, I);
                [px,py] = gradient(II);
                p=max(max(sqrt(px.^2+py.^2)));
                px = px/(p);
                py = py/(p);
             
                [Bz, DES] = ReceivedField(I, nx*ny*nz, N, S, Node, ROI, Bdes);
                Krit = max(max(max(abs(DES)./max(max(max(abs(Bdes)))))))*100;
                
                [Fi_int, L_int, I_int, Red, Blue, Red1, Blue1, Red2, Blue2] = InterpCircuit(R, L, nfi, nL, I, Nc, eb, er, zeror);
                
                cla(app.UIAxes_2, 'reset')
                switch app.DropDown_3.Value
                    case "Потоковая функция"
                        
                        contourf(app.UIAxes_2,Fi,Ll*1000,II);
                        %box(app.UIAxes_2,"on")
                        xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L, мм')
                        colorbar(app.UIAxes_2)
                        
                    case "Потоковая функция + Grad"
                        
                        app.Button_7.Enable = "on";
                        contourf(app.UIAxes_2,Fi,Ll*1000,II);
                        %box(app.UIAxes_2,"on")
                        hold(app.UIAxes_2,"on")
                        quiver(app.UIAxes_2,Fi,Ll*1000,px,py,0.2,'*r', 'MarkerSize', 2, 'LineWidth', 0.5)
                        xlabel(app.UIAxes_2,'fi'), ylabel(app.UIAxes_2,'L, мм')
                        colorbar(app.UIAxes_2)
                        hold(app.UIAxes_2,"off")
                        
                    case "Намотка 2D"
                        
                        app.Button_7.Enable = "on";
                        if ~isempty(Red2)
                            plot(app.UIAxes_2,Red2(:,2)*1000, Red2(:,1)*1000, '.r','MarkerSize',1)
                            hold(app.UIAxes_2,"on")
                        end
                        if ~isempty(Blue2)
                            plot(app.UIAxes_2,Blue2(:,2)*1000, Blue2(:,1)*1000, '.b','MarkerSize',1)
                        end
                        %box(app.UIAxes_2,"on")
                        rectangle(app.UIAxes_2,'Position', [-W/2 -LL W 2*LL])
                        if axial
                            xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'z, мм')
                        else 
                            xlabel(app.UIAxes_2,'r, мм'), ylabel(app.UIAxes_2,'x, мм')
                        end
                        axis(app.UIAxes_2,"equal")
                        grid(app.UIAxes_2,"on")
                        hold(app.UIAxes_2,"off")
                        
                    case "Намотка 3D"
                        if axial  
                            if ~isempty(Red1)
                                Red1 = rotY(Red1, pi/2);
                            end
                            if ~isempty(Blue1)
                                Blue1 = rotY(Blue1, pi/2);
                            end
                        end
                        if ~isempty(Red1)
                            plot3(app.UIAxes_2, Red1(:,1)*1000, Red1(:,2)*1000, Red1(:,3)*1000, '.r','MarkerSize',1)
                            hold(app.UIAxes_2,"on")
                        end
                        if ~isempty(Blue1)
                            plot3(app.UIAxes_2,Blue1(:,1)*1000, Blue1(:,2)*1000, Blue1(:,3)*1000, '.b','MarkerSize',1)
                        end
                        %box(app.UIAxes_2,"on")
                        xlabel(app.UIAxes_2,'x, мм'), ylabel(app.UIAxes_2,'y, мм'), zlabel(app.UIAxes_2,'z, мм')
                        axis(app.UIAxes_2,"equal")
                        grid(app.UIAxes_2,"on")
                        hold(app.UIAxes_2,"off")
                        
                end
                
                [X1,Y1,Z1,Bz] = Transform3D(lx, ly, lz, nx, ny, nz, Bz);
                [X1,Y1,Z1,DES1] = Transform3D(lx, ly, lz, nx, ny, nz, DES);
                DES1 = 100*DES1./max(max(max(Bdes)));
                
                if ~div
                    cla(app.UIAxes_4, 'reset')
                    if (plane == 0) % ---------------------------------------------------------- XY
                        k = find(abs(Z1(1,1,:)-level) < 0.00001);
                        contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, Bz(:,:,k)*1000)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, z = ', num2str(level*1000), 'мм')) 
                    elseif (plane == 1) % ------------------------------------------------------ XZ
                        k = find(abs(Y1(1,:,1)-level) < 0.00001);
                        XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                        ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                        BB1 = reshape(Bz(:,k,:),[nx,nz,1]);
                        contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1*1000)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, y = ', num2str(level*1000), 'мм'))
                    elseif (plane == 2) % ------------------------------------------------------ YZ
                        k = find(abs(X1(:,1,1)-level) < 0.00001);
                        YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                        ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                        BB1 = reshape(Bz(k,:,:),[ny,nz,1]);
                        contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1*1000)
                        xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Полученное поле, мТ, x = ', num2str(level*1000), 'мм'))
                    else
                        disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                    end
                    colorbar(app.UIAxes_4)
                    box(app.UIAxes_4,"on")
                    grid(app.UIAxes_4,"on")
                else
                    cla(app.UIAxes_4, 'reset')
                    if (plane == 0) % ---------------------------------------------------------- XY
                        k = find(abs(Z1(1,1,:)-level) < 0.00001);
                        contourf(app.UIAxes_4,X1(:,:,k)*1000, Y1(:,:,k)*1000, DES1(:,:,k))
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'y, мм'), title(app.UIAxes_4,strcat('Отклонение, %, z = ', num2str(level*1000), 'мм')) 
                    elseif (plane == 1) % ------------------------------------------------------ XZ
                        k = find(abs(Y1(1,:,1)-level) < 0.00001);
                        XX1 = reshape(X1(:,k,:),[nx,nz,1]);
                        ZZ1 = reshape(Z1(:,k,:),[nx,nz,1]);
                        BB1 = reshape(DES1(:,k,:),[nx,nz,1]);
                        contourf(app.UIAxes_4,XX1*1000, ZZ1*1000, BB1)
                        xlabel(app.UIAxes_4,'x, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, y = ', num2str(level*1000), 'мм'))
                    elseif (plane == 2) % ------------------------------------------------------ YZ
                        k = find(abs(X1(:,1,1)-level) < 0.00001);
                        YY1 = reshape(Y1(k,:,:),[ny,nz,1]);
                        ZZ1 = reshape(Z1(k,:,:),[ny,nz,1]);
                        BB1 = reshape(DES1(k,:,:),[ny,nz,1]);
                        contourf(app.UIAxes_4,YY1*1000, ZZ1*1000, BB1)
                        xlabel(app.UIAxes_4,'y, мм'), ylabel(app.UIAxes_4,'z, мм'), title(app.UIAxes_4,strcat('Отклонение, %, x = ', num2str(level*1000), 'мм'))
                    else
                        disp('___ОШИБКА ВЫБОРА ПЛОСКОСТИ___');
                    end
                    colorbar(app.UIAxes_4)
                    box(app.UIAxes_4,"on")
                    grid(app.UIAxes_4,"on")
                end
                
                app.DropDown_3.Visible = "on";
                app.DropDown_3.Enable = "on";
                app.UIAxes_2.Visible = "on";
                app.UIAxes_4.Visible = "on";
                app.Label_32.Visible = "on";
                app.Label_32.Text = strcat("Максимальное отклонение: ", num2str(Krit), ' %');
                app.Button_5.Enable = "on";
                app.Button_6.Enable = "on";
                if (app.DropDown_3.Value == "Намотка 3D") || (app.DropDown_3.Value == "Намотка 2D")
                     app.Button_5.Enable = "on";
                end
                app.PushTool2.Enable = "on";
                app.Button_4.Enable = "on";
                app.DropDown_3.Enable = "on";
                app.DropDown_5.Visible = "on";
                app.DropDown_5.Enable = "on";
            else 
                app.Panel.Enable = "on";
                app.Button_4.Enable = "off";
                app.DropDown.Enable = "on";
                app.EditField_11.Enable = "on";
                app.EditField_18.Enable = "on";
                if ((app.DropDown_3.Value == "Потоковая функция + Grad") && enb == 1) || ((app.DropDown_3.Value == "Намотка 2D") &&  enb == 1)
                     app.Button_7.Enable = "on";
                end
                if ((app.DropDown_3.Value == "Намотка 3D") && enb == 1) || ((app.DropDown_3.Value == "Намотка 2D") &&  enb == 1)
                     app.Button_5.Enable = "on";
                end
                if enb == 1
                    app.DropDown_3.Enable = "on";
                    app.DropDown_5.Enable = "on";
                end
            end
            
            app.CheckBox_2.Enable = "on";
            app.PushTool.Enable = "on";
            app.Panel_6.Enable = "on";
            app.Panel_2.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button.Enable = "on";
            app.Button_8.Enable = "on";
            app.EditField_19.Enable = "on";
            app.Lamp.Color = [0,1,0];
            app.Label_13.Text = "Готово!";
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            global sw
            value = app.CheckBox_2.Value;
            sw = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'load4.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Enable = 'off';
            app.PushTool2.Icon = 'save.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Сетка')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Целевое поле')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.Visible = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'Построить'; 'сетку'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Настройки поля';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = 'Тип градиента';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'X', 'Y', 'Z', 'XY', 'XZ', 'YZ', 'X2 - Y2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'X';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = 'Величина';
            app.EditField_11 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Interpreter = 'html';
            app.Label_8.Text = 'мТ/м';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = 'Плоскость';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'XY', 'XZ', 'YZ'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'XY';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = 'мм';
            app.Label_31 = uilabel(app.Panel_2);
            app.Label_31.Text = 'Уровень';
            app.DropDown_4 = uidropdown(app.Panel_2);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = 'Смещение';
            app.EditField_18 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.Label_38 = uilabel(app.Panel_2);
            app.Label_38.Text = 'мТ';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Оптимизация';
            app.Image = uiimage(app.Panel_4);
            app.Image.ImageSource = 'F.png';
            app.AlpfaEditFieldLabel = uilabel(app.Panel_4);
            app.AlpfaEditFieldLabel.Text = 'Alpfa';
            app.AlpfaEditField = uieditfield(app.Panel_4, 'numeric');
            app.AlpfaEditField.Limits = [0 Inf];
            app.AlpfaEditField.ValueChangedFcn = createCallbackFcn(app, @AlpfaEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.Panel_4);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.Panel_4, 'numeric');
            app.BetaEditField.Limits = [0 Inf];
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.Label_26 = uilabel(app.Panel_4);
            app.Label_26.Text = 'Уд. сопротивление';
            app.EditField_16 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.Label_27 = uilabel(app.Panel_4);
            app.Label_27.Text = 'Толщина';
            app.EditField_17 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.Label_30 = uilabel(app.Panel_4);
            app.Label_30.Text = 'мм';
            app.sup2supLabel = uilabel(app.Panel_4);
            app.sup2supLabel.Interpreter = 'html';
            app.sup2supLabel.Text = 'мм<sup>2</sup>Ом/м';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = 'Расчет';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Title = 'Настройки области интереса';
            app.XSpinnerLabel = uilabel(app.Panel_6);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.Panel_6);
            app.XSpinner.Limits = [3 Inf];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.XSpinner.Value = 3;
            app.YSpinnerLabel = uilabel(app.Panel_6);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.Panel_6);
            app.YSpinner.Limits = [3 Inf];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.YSpinner.Value = 3;
            app.ZLabel = uilabel(app.Panel_6);
            app.ZLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.Panel_6);
            app.ZSpinner.Limits = [3 Inf];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.ZSpinner.Value = 3;
            app.Label_2 = uilabel(app.Panel_6);
            app.Label_2.Text = 'Кол-во точек';
            app.Label_3 = uilabel(app.Panel_6);
            app.Label_3.Text = 'Размер';
            app.EditField_5 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_5.Limits = [1 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 1;
            app.EditField_6 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_6.Limits = [1 Inf];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 1;
            app.EditField_7 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_7.Limits = [1 Inf];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1;
            app.EditField_8 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.Label_4 = uilabel(app.Panel_6);
            app.Label_4.Text = 'Положение ';
            app.Label_16 = uilabel(app.Panel_6);
            app.Label_16.Text = 'мм';
            app.Label_17 = uilabel(app.Panel_6);
            app.Label_17.Text = 'мм';
            app.Label_18 = uilabel(app.Panel_6);
            app.Label_18.Text = 'мм';
            app.Label_19 = uilabel(app.Panel_6);
            app.Label_19.Text = 'мм';
            app.Label_35 = uilabel(app.Panel_6);
            app.Label_35.Text = 'мм';
            app.Label_36 = uilabel(app.Panel_6);
            app.Label_36.Text = 'мм';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Настройки сетки';
            app.REditFieldLabel = uilabel(app.Panel);
            app.REditFieldLabel.Text = 'Радиус катушки R';
            app.REditField = uieditfield(app.Panel, 'numeric');
            app.REditField.Limits = [1 Inf];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = 'Длина катушки L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.LEditField.Limits = [1 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Аксиальное поле';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = 'мм';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = 'мм';
            app.Label_33 = uilabel(app.Panel);
            app.Label_33.Text = {'Кол-во точек'; ' по окружности'};
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_2.Limits = [2 Inf];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 2;
            app.Label_34 = uilabel(app.Panel);
            app.Label_34.Text = 'Кол-во точек в длину';
            app.Spinner_3 = uispinner(app.Panel);
            app.Spinner_3.Limits = [2 Inf];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Value = 2;
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Wb.png';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = 'Готово!';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'Затраченное время:';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '0 с';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = 'Настройка контура';
            app.Label_20 = uilabel(app.Panel_5);
            app.Label_20.Text = 'Кол-во витков';
            app.Spinner = uispinner(app.Panel_5);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Label_21 = uilabel(app.Panel_5);
            app.Label_21.Text = 'Уровень нуля';
            app.EditField_13 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.Label_22 = uilabel(app.Panel_5);
            app.Label_22.Text = 'Ширина';
            app.EditField_14 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_14.Limits = [0 Inf];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.Label_23 = uilabel(app.Panel_5);
            app.Label_23.Text = 'Ширина';
            app.EditField_15 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_15.Limits = [0 Inf];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.Label_24 = uilabel(app.Panel_5);
            app.Label_24.Text = '%';
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '%';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'Потоковая функция', 'Потоковая функция + Grad', 'Намотка 2D', 'Намотка 3D'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Visible = 'off';
            app.DropDown_3.Value = 'Потоковая функция';
            app.Label_32 = uilabel(app.UIFigure);
            app.Label_32.Visible = 'off';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = 'Сброс';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'Полученное поле', 'Разность'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Visible = 'off';
            app.DropDown_5.Value = 'Полученное поле';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = {'Построить'; 'поле'};
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = {'Применить'; 'накстроки контура'};
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Text = 'Сохранить карту/схему';
            app.Label_39 = uilabel(app.UIFigure);
            app.Label_39.Visible = 'off';
            app.Label_39.Text = 'Узлов: 0          Элементов: 0';
            app.EditField_19 = uieditfield(app.UIFigure, 'text');
            app.EditField_19.Editable = 'off';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = 'Выбор папки';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = 'Сохранение осей';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end