classdef SFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        PushTool2            matlab.ui.container.toolbar.PushTool
        Button_12            matlab.ui.control.Button
        DropDown_5           matlab.ui.control.DropDown
        Button_9             matlab.ui.control.Button
        Panel_6              matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        XLabel_4             matlab.ui.control.Label
        YLabel_4             matlab.ui.control.Label
        ZLabel_4             matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        EditField_5          matlab.ui.control.NumericEditField
        EditField_4          matlab.ui.control.NumericEditField
        EditField_3          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        Label_17             matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        Spinner_5            matlab.ui.control.Spinner
        Spinner_4            matlab.ui.control.Spinner
        Spinner_3            matlab.ui.control.Spinner
        Label_16             matlab.ui.control.Label
        Label_15             matlab.ui.control.Label
        Label_14             matlab.ui.control.Label
        Label_13             matlab.ui.control.Label
        XLabel               matlab.ui.control.Label
        YLabel               matlab.ui.control.Label
        ZLabel               matlab.ui.control.Label
        Label_12             matlab.ui.control.Label
        Label_11             matlab.ui.control.Label
        Label_10             matlab.ui.control.Label
        Label_9              matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        XLabel_3             matlab.ui.control.Label
        YLabel_3             matlab.ui.control.Label
        ZLabel_3             matlab.ui.control.Label
        Label_28             matlab.ui.control.Label
        EditField_12         matlab.ui.control.NumericEditField
        EditField_11         matlab.ui.control.NumericEditField
        EditField_10         matlab.ui.control.NumericEditField
        EditField_7          matlab.ui.control.NumericEditField
        Spinner_8            matlab.ui.control.Spinner
        Spinner_7            matlab.ui.control.Spinner
        Spinner_6            matlab.ui.control.Spinner
        Label_25             matlab.ui.control.Label
        Label_24             matlab.ui.control.Label
        Label_23             matlab.ui.control.Label
        RLabel               matlab.ui.control.Label
        Label_27             matlab.ui.control.Label
        Label_21             matlab.ui.control.Label
        Label_20             matlab.ui.control.Label
        Label_19             matlab.ui.control.Label
        Label_18             matlab.ui.control.Label
        Tab_3                matlab.ui.container.Tab
        Label_64             matlab.ui.control.Label
        Label_62             matlab.ui.control.Label
        Button_11            matlab.ui.control.Button
        Label_63             matlab.ui.control.Label
        ROILabel             matlab.ui.control.Label
        Button_10            matlab.ui.control.Button
        EditField_26         matlab.ui.control.EditField
        Button_8             matlab.ui.control.Button
        Label_58             matlab.ui.control.Label
        DropDown_4           matlab.ui.control.DropDown
        Label_57             matlab.ui.control.Label
        Label_56             matlab.ui.control.Label
        Label_55             matlab.ui.control.Label
        Lamp                 matlab.ui.control.Lamp
        Button_7             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Panel_5              matlab.ui.container.Panel
        Label_54             matlab.ui.control.Label
        Label_53             matlab.ui.control.Label
        EditField_25         matlab.ui.control.NumericEditField
        Label_52             matlab.ui.control.Label
        EditField_24         matlab.ui.control.NumericEditField
        Label_51             matlab.ui.control.Label
        EditField_23         matlab.ui.control.NumericEditField
        Label_50             matlab.ui.control.Label
        Spinner_12           matlab.ui.control.Spinner
        Label_49             matlab.ui.control.Label
        Panel_4              matlab.ui.container.Panel
        sup2supLabel         matlab.ui.control.Label
        Label_48             matlab.ui.control.Label
        EditField_22         matlab.ui.control.NumericEditField
        Label_47             matlab.ui.control.Label
        EditField_21         matlab.ui.control.NumericEditField
        Label_46             matlab.ui.control.Label
        BetaEditField        matlab.ui.control.NumericEditField
        BetaEditFieldLabel   matlab.ui.control.Label
        AlpfaEditField       matlab.ui.control.NumericEditField
        AlpfaEditFieldLabel  matlab.ui.control.Label
        Image2               matlab.ui.control.Image
        Panel_3              matlab.ui.container.Panel
        Label_45             matlab.ui.control.Label
        DropDown_3           matlab.ui.control.DropDown
        Label_44             matlab.ui.control.Label
        DropDown_2           matlab.ui.control.DropDown
        Label_43             matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Label_42             matlab.ui.control.Label
        Label_41             matlab.ui.control.Label
        Label_40             matlab.ui.control.Label
        EditField_20         matlab.ui.control.NumericEditField
        Label_39             matlab.ui.control.Label
        EditField_19         matlab.ui.control.NumericEditField
        Label_38             matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        EditField_18         matlab.ui.control.NumericEditField
        EditField_17         matlab.ui.control.NumericEditField
        EditField_16         matlab.ui.control.NumericEditField
        EditField_15         matlab.ui.control.NumericEditField
        EditField_14         matlab.ui.control.NumericEditField
        Label_37             matlab.ui.control.Label
        EditField_13         matlab.ui.control.NumericEditField
        Spinner_11           matlab.ui.control.Spinner
        Spinner_10           matlab.ui.control.Spinner
        Spinner_9            matlab.ui.control.Spinner
        Label_36             matlab.ui.control.Label
        Label_35             matlab.ui.control.Label
        Label_34             matlab.ui.control.Label
        Label_33             matlab.ui.control.Label
        XLabel_2             matlab.ui.control.Label
        YLabel_2             matlab.ui.control.Label
        ZLabel_2             matlab.ui.control.Label
        Label_32             matlab.ui.control.Label
        Label_31             matlab.ui.control.Label
        Label_30             matlab.ui.control.Label
        Label_29             matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        Label_60             matlab.ui.control.Label
        Label_59             matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        Label_7              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Image                matlab.ui.control.Image
        ButtonGroup          matlab.ui.container.ButtonGroup
        Button_2             matlab.ui.control.RadioButton
        Button               matlab.ui.control.RadioButton
        Spinner_2            matlab.ui.control.Spinner
        Label_2              matlab.ui.control.Label
        Spinner              matlab.ui.control.Spinner
        Label                matlab.ui.control.Label
        LEditField           matlab.ui.control.NumericEditField
        LEditFieldLabel      matlab.ui.control.Label
        REditField           matlab.ui.control.NumericEditField
        REditFieldLabel      matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Web CubeROI SphereROI FOV Grad ROI_form done mode field_dif Optimisation Contour Results path last_path flag figWeb figField figFieldRes figRes ROI_selected
            
            clc
            close all
            
            Web = struct('R', 0.0665, 'L', 0.15, 'nfi', 15, 'nL', 11, 'axial', 1, 'Node', [], 'S', [], 'I', []);
            
            CubeROI = struct('nx', 7, 'ny', 7, 'nz', 7, 'lx', 0.05, 'ly', 0.05, 'lz', 0.05, 'center', [0 0 0]);
            
            SphereROI = struct('nR', 5, 'nksi', 14, 'ntetta', 7, 'Rsp', 0.025, 'center', [0 0 0]);
            
            FOV = struct('nx', 15, 'ny', 15, 'nz', 15, 'lx', 0.05, 'ly', 0.05, 'lz', 0.05, 'center', [0 0 0], 'level', 0, 'plane', 'XY', 'FOV', [], 'B_target', [], 'B_received', [], 'B_difference', []);
            
            Grad = struct('type', 'X', 'value', 0.05, 'boff', 0, 'ROI', [], 'B_target', [], 'B_received', [], 'B_difference', []);
            
            Optimisation = struct('ro', 0.017, 't', 2, 'alpfa', 1500, 'beta', 0.0005);
            
            Contour = struct('zero', 0, 'Nc', 5, 'offb', 0.005, 'offr', 0.005, 'Red_2D', [], 'Blue_2D', [], 'Red_3D', [], 'Blue_3D', []);
            
            Results = struct('Fi', [], 'Ll', [], 'II', [], 'px', [], 'py', []);
            
            ROI_form = 0;   % 0) Куб; 1) Сфера; 2) Произвольно
            done = 0;       % 0) Расчет не проведен; 1) Расчет готов
            mode = 0;       % 0) Намотка 2D; 1) Намотка 3D; 2) Потоковая функция; 3) Потоковая функция + grad
            field_dif = 0;  % 0) Поле; 1) Разность
            flag = 0;       % Пересчет поля
            ROI_selected = 0;
            
            figWeb = 0;     % Фигура с сеткой
            figField = 0;   % Фигура с целевым полем
            figFieldRes = 0;    % Фигура с полученным полем
            figRes = 0;     % Фигура с результатами
            
            path = pwd;
            path = strcat(path, '\');
            last_path = path;
            
            Levels = linspace(FOV.lz/2, -FOV.lz/2, FOV.nz)*1000;
            
            app.REditField.Value = Web.R*1000;
            app.LEditField.Value = Web.L*1000;
            app.Spinner.Value = Web.nfi;
            app.Spinner_2.Value = Web.nL;
            if Web.axial
                app.Button.Value = 1;
            else
                app.Button_2.Value = 1;
            end
            
            switch ROI_form
                case 0
                   app.TabGroup.SelectedTab = app.Tab;
                case 1
                   app.TabGroup.SelectedTab = app.Tab_2;
                case 2
                   app.TabGroup.SelectedTab = app.Tab_3;
            end
            
            app.Spinner_3.Value = CubeROI.nx;
            app.Spinner_4.Value = CubeROI.ny;
            app.Spinner_5.Value = CubeROI.nz;
            app.EditField.Value = CubeROI.lx*1000;
            app.EditField_2.Value = CubeROI.ly*1000;
            app.EditField_3.Value = CubeROI.lz*1000;
            app.EditField_4.Value = CubeROI.center(1)*1000;
            app.EditField_5.Value = CubeROI.center(2)*1000;
            app.EditField_6.Value = CubeROI.center(3)*1000;
            
            app.Spinner_6.Value = SphereROI.nR;
            app.Spinner_7.Value = SphereROI.nksi;
            app.Spinner_8.Value = SphereROI.ntetta;
            app.EditField_7.Value = SphereROI.Rsp*1000;
            app.EditField_10.Value = SphereROI.center(1)*1000;
            app.EditField_11.Value = SphereROI.center(2)*1000;
            app.EditField_12.Value = SphereROI.center(3)*1000;
            
            app.Spinner_9.Value = FOV.nx;
            app.Spinner_10.Value = FOV.ny;
            app.Spinner_11.Value = FOV.nz;
            app.EditField_13.Value = FOV.lx*1000;
            app.EditField_14.Value = FOV.ly*1000;
            app.EditField_15.Value = FOV.lz*1000;
            app.EditField_16.Value = FOV.center(1)*1000;
            app.EditField_17.Value = FOV.center(2)*1000;
            app.EditField_18.Value = FOV.center(3)*1000;
            
            app.DropDown.Value = Grad.type;
            app.EditField_19.Value = Grad.value*1000;
            app.EditField_20.Value = Grad.boff*1000;
            
            app.DropDown_2.Value = FOV.plane;
            app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
            app.DropDown_3.Value = num2str(FOV.level*1000);
            
            app.AlpfaEditField.Value = Optimisation.alpfa/100;
            app.BetaEditField.Value = Optimisation.beta*10000;
            app.EditField_21.Value = Optimisation.ro;
            app.EditField_22.Value = Optimisation.t;
            
            app.Spinner_12.Value = Contour.Nc;
            app.EditField_23.Value = Contour.zero;
            app.EditField_24.Value = Contour.offb*100;
            app.EditField_25.Value = Contour.offr*100;
            
            app.EditField_26.Value = path;
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            global Web
            Web.R = app.REditField.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            global Web
            Web.L = app.LEditField.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            global Web
            Web.nfi = app.Spinner.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            global Web
            Web.nL = app.Spinner_2.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global ROI_form ROI_selected
            switch app.TabGroup.SelectedTab.Title
                case "Куб"
                    ROI_form = 0;
                    app.DropDown.Enable = "on";                    
                    app.EditField_19.Enable = "on";                    
                    app.EditField_20.Enable = "on";
                    app.Button_3.Enable = "on";
                case "Сфера"
                    ROI_form = 1;
                    app.DropDown.Enable = "on";                    
                    app.EditField_19.Enable = "on";                    
                    app.EditField_20.Enable = "on";
                    app.Button_3.Enable = "on";
                case "Произвольно"
                    ROI_form = 2;
                    app.DropDown.Enable = "off";                    
                    app.EditField_19.Enable = "off";                    
                    app.EditField_20.Enable = "off";
                    if ROI_selected == 0
                        app.Button_3.Enable = "off";
                        app.Label_64.Text = "Не выбрано...";
                    end
            end
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            global CubeROI
            CubeROI.nx = app.Spinner_3.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_4
        function Spinner_4ValueChanged(app, event)
            global CubeROI
            CubeROI.ny = app.Spinner_4.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_5
        function Spinner_5ValueChanged(app, event)
            global CubeROI
            CubeROI.nz = app.Spinner_5.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global CubeROI
            CubeROI.lx = app.EditField.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            global CubeROI
            CubeROI.ly = app.EditField_2.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            global CubeROI
            CubeROI.lz = app.EditField_3.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            global CubeROI
            CubeROI.center(1) = app.EditField_4.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            global CubeROI
            CubeROI.center(2) = app.EditField_5.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            global CubeROI
            CubeROI.center(3) = app.EditField_6.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global Web
            switch app.ButtonGroup.SelectedObject.Text
                case "Аксиальная"
                    Web.axial = 1;
                case "Хальбаха"
                    Web.axial = 0;
            end
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_6
        function Spinner_6ValueChanged(app, event)
            global SphereROI
            SphereROI.nR = app.Spinner_6.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_7
        function Spinner_7ValueChanged(app, event)
            global SphereROI
            SphereROI.nksi = app.Spinner_7.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_8
        function Spinner_8ValueChanged(app, event)
            global SphereROI
            SphereROI.ntetta = app.Spinner_8.Value;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            global SphereROI
            SphereROI.Rsp = app.EditField_7.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            global SphereROI
            SphereROI.center(1) = app.EditField_10.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            global SphereROI
            SphereROI.center(2) = app.EditField_11.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            global SphereROI
            SphereROI.center(3) = app.EditField_12.Value/1000;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_9
        function Spinner_9ValueChanged(app, event)
            global FOV flag
            FOV.nx = app.Spinner_9.Value;
            if FOV.plane == "YZ"
                Levels = linspace(FOV.lx/2, -FOV.lx/2, FOV.nx)*1000 + FOV.center(1)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_10
        function Spinner_10ValueChanged(app, event)
            global FOV flag
            FOV.ny = app.Spinner_10.Value;
            if FOV.plane == "XZ"
                Levels = linspace(FOV.ly/2, -FOV.ly/2, FOV.ny)*1000 + FOV.center(2)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: Spinner_11
        function Spinner_11ValueChanged(app, event)
            global FOV flag
            FOV.nz = app.Spinner_11.Value;
            if FOV.plane == "XY"
                Levels = linspace(FOV.lz/2, -FOV.lz/2, FOV.nz)*1000 + FOV.center(3)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            global FOV flag
            FOV.lx = app.EditField_13.Value/1000;
            if FOV.plane == "YZ"
                Levels = linspace(FOV.lx/2, -FOV.lx/2, FOV.nx)*1000 + FOV.center(1)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            global FOV flag
            FOV.ly = app.EditField_14.Value/1000;
            if FOV.plane == "XZ"
                Levels = linspace(FOV.ly/2, -FOV.ly/2, FOV.ny)*1000 + FOV.center(2)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            global FOV flag
            FOV.lz = app.EditField_15.Value/1000;
            if FOV.plane == "XY"
                Levels = linspace(FOV.lz/2, -FOV.lz/2, FOV.nz)*1000 + FOV.center(3)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            global FOV flag
            FOV.center(1) = app.EditField_16.Value/1000;
            if FOV.plane == "YZ"
                Levels = linspace(FOV.lx/2, -FOV.lx/2, FOV.nx)*1000 + FOV.center(1)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            global FOV flag
            FOV.center(2) = app.EditField_17.Value/1000;
            if FOV.plane == "XZ"
                Levels = linspace(FOV.ly/2, -FOV.ly/2, FOV.ny)*1000 + FOV.center(2)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            global FOV flag
            FOV.center(3) = app.EditField_18.Value/1000;
            if FOV.plane == "XY"
                Levels = linspace(FOV.lz/2, -FOV.lz/2, FOV.nz)*1000 + FOV.center(3)*1000;
                FOV.level = Levels(1)/1000;
                app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                app.DropDown_3.Value = num2str(FOV.level*1000);
            end
            flag = 1;
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global Grad
            Grad.type = app.DropDown.Value;
            if (Grad.type == "X") || (Grad.type == "Y") || (Grad.type == "Z") 
                app.Label_41.Text = "мТ/м";
            else
                app.Label_41.Text = "мТ/м<sup>2</sup>";
            end
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_19
        function EditField_19ValueChanged(app, event)
            global Grad
            Grad.value = app.EditField_19.Value/1000;
            app.Button_5.Enable = "off";
        end

        % Value changed function: EditField_20
        function EditField_20ValueChanged(app, event)
            global Grad
            Grad.boff = app.EditField_20.Value/1000;
            app.Button_5.Enable = "off";
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            global FOV
            FOV.plane = app.DropDown_2.Value;
            switch FOV.plane
                case "XY"
                    Levels = linspace(FOV.lz/2, -FOV.lz/2, FOV.nz)*1000 + FOV.center(3)*1000;
                    FOV.level = Levels(1)/1000;
                    app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                    app.DropDown_3.Value = num2str(FOV.level*1000);
                case "XZ"
                    Levels = linspace(FOV.ly/2, -FOV.ly/2, FOV.ny)*1000 + FOV.center(2)*1000;
                    FOV.level = Levels(1)/1000;
                    app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                    app.DropDown_3.Value = num2str(FOV.level*1000);
                case "YZ"
                    Levels = linspace(FOV.lx/2, -FOV.lx/2, FOV.nx)*1000 + FOV.center(1)*1000;
                    FOV.level = Levels(1)/1000;
                    app.DropDown_3.Items = strtrim(cellstr(num2str(Levels')));
                    app.DropDown_3.Value = num2str(FOV.level*1000);
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            global FOV
            FOV.level = str2double(convertCharsToStrings(app.DropDown_3.Value))/1000;
%             switch FOV.plane
%                 case "XY"
%                     FOV.level = value - FOV.center(3);
%                     %app.DropDown_3.Value = num2str(FOV.level*1000);
%                 case "XZ"
%                     FOV.level = value - FOV.center(2);
%                     %app.DropDown_3.Value = num2str(FOV.level*1000);
%                 case "YZ"
%                     FOV.level = value - FOV.center(1);
%                     %app.DropDown_3.Value = num2str(FOV.level*1000);
%             end
        end

        % Value changed function: AlpfaEditField
        function AlpfaEditFieldValueChanged(app, event)
            global Optimisation
            Optimisation.alpfa = app.AlpfaEditField.Value*100;
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            global Optimisation
            Optimisation.beta = app.BetaEditField.Value/10000;
        end

        % Value changed function: EditField_21
        function EditField_21ValueChanged(app, event)
            global Optimisation
            Optimisation.ro = app.EditField_21.Value;
        end

        % Value changed function: EditField_22
        function EditField_22ValueChanged(app, event)
            global Optimisation
            Optimisation.t = app.EditField_22.Value;
        end

        % Value changed function: Spinner_12
        function Spinner_12ValueChanged(app, event)
            global Contour
            Contour.Nc = app.Spinner_12.Value;
        end

        % Value changed function: EditField_23
        function EditField_23ValueChanged(app, event)
            global Contour
            Contour.zero = app.EditField_23.Value;
        end

        % Value changed function: EditField_25
        function EditField_25ValueChanged(app, event)
            global Contour
            Contour.offr = app.EditField_25.Value/100;
        end

        % Value changed function: EditField_24
        function EditField_24ValueChanged(app, event)
            global Contour
            Contour.offb = app.EditField_24.Value/100;
        end

        % Button pushed function: Button_3
        function NodeSROIFOV(app, event)
            global Web ROI_form CubeROI SphereROI FOV Grad done figWeb ROI_selected
            
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Создание сетки...";
            
            pause(0.1)
            
            Web.Node = CreateNode(Web.R, Web.L, Web.nfi, Web.nL, Web.axial);
            Web.S = CreateS(Web.nfi*Web.nL, Web.nfi);
            
            FOV.FOV = CreateCubeROI(FOV.nx, FOV.ny, FOV.nz, FOV.lx, FOV.ly, FOV.lz, FOV.center);
            
            %app.UIAxes.Visible = "on";
            %app.UIAxes.Toolbar.Visible = "on";
            switch ROI_form
                case 0
                    Grad.ROI = CreateCubeROI(CubeROI.nx, CubeROI.ny, CubeROI.nz, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center);
                    figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);
                    ROI_selected = 0;
                case 1
                    Grad.ROI = CreateSphereROI(SphereROI.nR, SphereROI.nksi, SphereROI.ntetta, SphereROI.Rsp, SphereROI.center);
                    figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);
                    ROI_selected = 0;
                case 2
                    figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);  
            end
            
            app.Label_59.Text = num2str(Web.nfi*Web.nL);
            app.Label_60.Text = num2str(length(Web.S));
            
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            if ~done
                app.Panel.Enable = "on";
                app.Panel_6.Enable = "on";
            end
            app.Panel_2.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            if done
                %app.Button_5.Enable = "on";
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_9.Enable = "on";
                app.PushTool2.Enable = "on";
            else 
                app.PushTool.Enable = "on";
            end
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
         
        end

        % Button pushed function: Button_4
        function Field(app, event)
            global ROI_form CubeROI SphereROI FOV Grad done field_dif flag Web figField figFieldRes
            
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Расчет поля...";
            
            pause(0.1)
            
            if ROI_form ~= 2
                switch Grad.type
                    case "X"
                        Grad.B_target = Grad.value*Grad.ROI(:,1) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,1) + Grad.boff;
                    case "Y"
                        Grad.B_target = Grad.value*Grad.ROI(:,2) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,2) + Grad.boff;
                    case "Z"
                        Grad.B_target = Grad.value*Grad.ROI(:,3) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,3) + Grad.boff;
                    case "XY"
                        Grad.B_target = Grad.value*Grad.ROI(:,1).*Grad.ROI(:,2) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,1).*FOV.FOV(:,2) + Grad.boff;
                    case "XZ"
                        Grad.B_target = Grad.value*Grad.ROI(:,1).*Grad.ROI(:,3) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,1).*FOV.FOV(:,3) + Grad.boff;
                    case "YZ"
                        Grad.B_target = Grad.value*Grad.ROI(:,2).*Grad.ROI(:,3) + Grad.boff;
                        FOV.B_target = Grad.value*FOV.FOV(:,2).*FOV.FOV(:,3) + Grad.boff;
                    case "X2-Y2"
                        Grad.B_target = Grad.value*(Grad.ROI(:,1).*Grad.ROI(:,1) - Grad.ROI(:,2).*Grad.ROI(:,2)) + Grad.boff;
                        FOV.B_target = Grad.value*(FOV.FOV(:,1).*FOV.FOV(:,1) - FOV.FOV(:,2).*FOV.FOV(:,2)) + Grad.boff;
                    case "X2"
                        Grad.B_target = Grad.value*abs(Grad.ROI(:,1)).*Grad.ROI(:,1) + Grad.boff;
                        FOV.B_target = Grad.value*abs(FOV.FOV(:,1)).*FOV.FOV(:,1) + Grad.boff;
                end
            else 
                FOV.B_target = ones(length(FOV.FOV(:,1)),1);
            end
            
            switch ROI_form
                case 0 
                    figField = PrintFieldCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figField);
                case 1
                    figField = PrintFieldSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figField);
                case 2 
                    figField = PrintFieldSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, 0, SphereROI.center, figField);
            end            

            if done
                if flag 
                    [FOV.B_received, FOV.B_difference] = ReceivedField(Web.I, FOV.nx*FOV.ny*FOV.nz, Web.nfi*Web.nL, Web.S, Web.Node, FOV.FOV, FOV.B_target);
                    flag = 0;
                end
                if ~field_dif
                    switch ROI_form
                        case 0 
                            figFieldRes = PrintFieldCubeRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                        case 1
                            figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                        case 2 
                            figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                    end  
                else
                    switch ROI_form
                        case 0 
                            figFieldRes = PrintDiffCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                        case 1
                            figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                        case 2 
                            figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                    end      
                end
            end
            
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            if ~done
                app.Panel.Enable = "on";
                app.Panel_6.Enable = "on";
            end
            app.Panel_2.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            if done
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_5.Enable = "off";
                app.Button_9.Enable = "on";
                app.PushTool2.Enable = "on";
            else
                app.PushTool.Enable = "on";
            end
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
        end

        % Button pushed function: Button_5
        function Calculation(app, event)
            global CubeROI SphereROI Web Optimisation ROI_form Grad FOV Contour mode field_dif done Results figFieldRes figRes
            
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Оптимизация...";
            
            tic
            pause(0.1)
            Web.I = ABI(Web.L, Web.R, CubeROI.lx, CubeROI.ly, CubeROI.lz, Web.nfi*Web.nL, Web.Node, Web.S, SphereROI.Rsp, Grad.ROI, Optimisation.ro, Optimisation.t, Optimisation.alpfa, Optimisation.beta, Grad.B_target, ROI_form);
            Web.I
            
            switch ROI_form
                case 0
                    [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, CubeROI.nx*CubeROI.ny*CubeROI.nz, Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
                case 1
                    [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, SphereROI.nR*SphereROI.nksi*SphereROI.ntetta, Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
                case 2
                    [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, length(Grad.ROI(:,1)), Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
            end
            
            [FOV.B_received, FOV.B_difference] = ReceivedField(Web.I, FOV.nx*FOV.ny*FOV.nz, Web.nfi*Web.nL, Web.S, Web.Node, FOV.FOV, FOV.B_target);
            
            Krit = max(abs(Grad.B_difference)./max(Grad.B_target, [], 'all'), [], 'all')*100;
            FOV.B_difference = 100*(FOV.B_difference)./max(FOV.B_target, [], 'all');

            [Results.px, Results.py, Results.Fi, Results.Ll, Results.II, Contour.Red_3D, Contour.Blue_3D, Contour.Red_2D, Contour.Blue_2D, figRes] = PrintResults(Web.R, Web.L, Web.nfi, Web.nL, Web.axial, Web.I, Contour.Nc, Contour.offb, Contour.offr, Contour.zero, mode, figRes);

            if ~field_dif
                switch ROI_form
                    case 0 
                        figFieldRes = PrintFieldCubeRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                    case 1
                        figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                    case 2 
                        figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                end  
            else
                switch ROI_form
                    case 0 
                        figFieldRes = PrintDiffCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                    case 1
                        figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                    case 2 
                        figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                end      
            end
            
            Time = toc;
            done = 1;
            
            app.DropDown_4.Enable = "on";
            app.DropDown_4.Visible = "on";
            app.DropDown_5.Enable = "on";
            app.DropDown_5.Visible = "on";
            app.Label_58.Visible = "on";
            app.Label_57.Text = strcat(num2str(round(Time)), " c");
            app.Label_58.Text = strcat("Максимальное отклонение: ", num2str(Krit), " %");
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            %app.Panel.Enable = "on";
            app.Panel_2.Enable = "on";
            %app.Panel_6.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            %app.Button_5.Enable = "on";
            app.Button_6.Enable = "on";
            app.Button_7.Enable = "on";
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.Button_9.Enable = "on";
            app.Button_12.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
            app.PushTool2.Enable = "on";
            app.DropDown.Enable = "off";
            app.EditField_19.Enable = "off";
            app.EditField_20.Enable = "off";
            app.PushTool.Enable = "off";
        end

        % Value changed function: DropDown_5
        function DifChange(app, event)
            global ROI_form CubeROI SphereROI FOV field_dif figFieldRes
            
            switch app.DropDown_5.Value
                case "Полученное поле"
                    field_dif = 0;
                case "Разность"
                    field_dif = 1;
            end
            
            if ~field_dif
                switch ROI_form
                    case 0 
                        figFieldRes = PrintFieldCubeRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                    case 1
                        figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                    case 2 
                        figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                end  
            else
                switch ROI_form
                    case 0 
                        figFieldRes = PrintDiffCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                    case 1
                        figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                    case 2 
                        figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                end      
            end
        end

        % Value changed function: DropDown_4
        function ModeChange(app, event)
            global Results Contour Web mode figRes
            switch app.DropDown_4.Value
                case "Намотка 2D"
                    mode = 0;
                case "Намотка 3D"
                    mode = 1;
                case "Потоковая функция"
                    mode = 2;
                case "Потоковая функция + grad"
                    mode = 3;
            end
            figRes = PrintResults2(Web.R, Web.L, Results.px, Results.py, Web.axial, Results.Fi, Results.Ll, Results.II, Contour.Red_3D, Contour.Blue_3D, Contour.Red_2D, Contour.Blue_2D, mode, figRes);
        end

        % Button pushed function: Button_6
        function SettingsContour(app, event)
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Изменение контура...";
            pause(0.1)
            
            global Web Contour mode Results done figRes
            [Results.px, Results.py, Results.Fi, Results.Ll, Results.II, Contour.Red_3D, Contour.Blue_3D, Contour.Red_2D, Contour.Blue_2D, figRes] = PrintResults(Web.R, Web.L, Web.nfi, Web.nL, Web.axial, Web.I, Contour.Nc, Contour.offb, Contour.offr, Contour.zero, mode, figRes);
            
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            if ~done
                app.Panel.Enable = "on";
                app.Panel_6.Enable = "on";
            end
            app.Panel_2.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            if done
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_5.Enable = "off";
                app.Button_9.Enable = "on";
                app.Button_12.Enable = "on";
                app.PushTool2.Enable = "on";
            end
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
            %app.PushTool.Enable = "on";
        end

        % Button pushed function: Button_8
        function ChangeFolder(app, event)
            global path last_path
            path = uigetdir;
            if path == 0
                path = last_path;
            else 
                last_path = path;
            end
            if path(length(path)) ~= '\'
                path = strcat(path,'\');
            end
            app.EditField_26.Value = path;
        end

        % Button pushed function: Button_9
        function SavePicture(app, event)
            global Web Contour mode done Grad Optimisation path figRes
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.Button_12.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Сохранение...";
            pause(0.1)
            
            switch mode
                case 0
                    st = "Circuit2D_";
                case 1
                    st = "Circuit3D_";
                case 2
                    st = "SF_";
                case 3
                    st = "SFGrad_";
            end
            
            str = strcat(st,Grad.type,"_",num2str(Web.axial),"_",num2str(Web.L*1000),"_",num2str(Web.R*1000),"_",num2str(Web.nfi),"_",num2str(Web.nL),"_",num2str(Optimisation.alpfa/100),"_",num2str(Optimisation.beta*10000),"_",num2str(Contour.Nc),".png");
            str = strcat(path,str);
            delete(str);
                       
            refreshdata(figRes)
            
            set(findobj(figRes, 'type','axes'), 'Visible','off')
            
            pause(1)
            exportgraphics(figRes,str,'Resolution',1200)
            
            %set(figRes,'Visible', 'on')
            set(findobj(figRes, 'type','axes'), 'Visible','on') 
            
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            if ~done
                app.Panel.Enable = "on";
                app.Panel_6.Enable = "on";
            end
            app.Panel_2.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            if done
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_5.Enable = "off";
                app.Button_9.Enable = "on";
                app.Button_12.Enable = "on";
                app.PushTool2.Enable = "on";
            else 
                app.PushTool.Enable = "on";
            end
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
            
        end

        % Clicked callback: PushTool2
        function SaveData(app, event)
            global Grad Web CubeROI SphereROI Optimisation ROI_form path last_path 
            
            L = Web.L;
            R = Web.R;
            nfi = Web.nfi;
            nL = Web.nL;
            B = Grad.boff;
            G = Grad.value;
            I = Web.I;
            a = Web.axial;

            switch ROI_form
                case 0
                    
                    f1 = "_Cube_";
                    f = 0;
                    cx = CubeROI.center(1);
                    cy = CubeROI.center(2);
                    cz = CubeROI.center(3);
                    lx = CubeROI.lx;
                    ly = CubeROI.ly;
                    lz = CubeROI.lz;
                    nx = CubeROI.nx;
                    ny = CubeROI.ny;
                    nz = CubeROI.nz;
                    switch Grad.type
                        case "X"
                            T = 0;
                        case "Y"
                            T = 1;
                        case "Z"
                            T = 2;
                        case "XY"
                            T = 3;
                        case "XZ"
                            T = 4;
                        case "YZ"
                            T = 5;
                        case "X2-Y2"
                            T = 6;
                        case "X2"
                            T = 7;
                    end

                    path = uigetdir;
                    if path == 0
                        path = last_path;
                    else 
                        last_path = path;
                    end
                    
                    if path(length(path)) ~= '\'
                        path = strcat(path,'\');
                    end
        
                    str = strcat("Out_I_",num2str(Grad.type),f1,num2str(Web.L*1000),"_",num2str(Web.R*1000),"_",num2str(Web.nfi),"_",num2str(Web.nL),"_",num2str(Web.axial),"_",num2str(Optimisation.alpfa/100),"_",num2str(Optimisation.beta*10000),".txt");
                    str = strcat(path,str);
                    delete(str)
                    save(str,"L","R","nfi","nL","a","f","lx","ly","lz","nx","ny","nz","cx","cy","cz","T","G","B","I","-ascii","-tabs")
                    
                case 1
                    f1 = "_Sphere_";
                    f = 1;
                    cx = SphereROI.center(1);
                    cy = SphereROI.center(2);
                    cz = SphereROI.center(3);
                    lx = SphereROI.Rsp;
                    ly = SphereROI.Rsp;
                    lz = SphereROI.Rsp;
                    nx = SphereROI.nR;
                    ny = SphereROI.nksi;
                    nz = SphereROI.ntetta;
                    switch Grad.type
                        case "X"
                            T = 0;
                        case "Y"
                            T = 1;
                        case "Z"
                            T = 2;
                        case "XY"
                            T = 3;
                        case "XZ"
                            T = 4;
                        case "YZ"
                            T = 5;
                        case "X2-Y2"
                            T = 6;
                        case "X2"
                            T = 7;
                    end
                      
                    path = uigetdir;
                    if path == 0
                        path = last_path;
                    else 
                        last_path = path;
                    end
                    
                    if path(length(path)) ~= '\'
                        path = strcat(path,'\');
                    end
        
                    str = strcat("Out_I_",num2str(Grad.type),f1,num2str(Web.L*1000),"_",num2str(Web.R*1000),"_",num2str(Web.nfi),"_",num2str(Web.nL),"_",num2str(Web.axial),"_",num2str(Optimisation.alpfa/100),"_",num2str(Optimisation.beta*10000),".txt");
                    str = strcat(path,str);
                    delete(str)
                    save(str,"L","R","nfi","nL","a","f","lx","ly","lz","nx","ny","nz","cx","cy","cz","T","G","B","I","-ascii")
                    
                case 2
                    L = [L 0 0];
                    R = [R 0 0];
                    nfi = [nfi 0 0];
                    nL = [nL 0 0];
                    a = [a 0 0];
                    I = [I zeros(length(I(:,1)),1) zeros(length(I(:,1)),1)];
                    
                    f1 = "_Opt_";
                    f = [2 0 0];
                    len = [length(Grad.ROI(:,1)) 0 0];
                    ROI_s = Grad.ROI;
                    B = [Grad.B_target zeros(length(Grad.B_target(:,1)),1) zeros(length(Grad.B_target(:,1)),1)];
                    
                    path = uigetdir;
                    if path == 0
                        path = last_path;
                    else 
                        last_path = path;
                    end
                    
                    if path(length(path)) ~= '\'
                        path = strcat(path,'\');
                    end
        
                    str = strcat("Out_I_",f1,num2str(Web.L*1000),"_",num2str(Web.R*1000),"_",num2str(Web.nfi),"_",num2str(Web.nL),"_",num2str(Web.axial),"_",num2str(Optimisation.alpfa/100),"_",num2str(Optimisation.beta*10000),".txt");
                    str = strcat(path,str);
                    delete(str)
                    save(str,"L","R","nfi","nL","a","f","len","ROI_s","B","I","-ascii")
            end

            pause(1)
        end

        % Clicked callback: PushTool
        function LoadData(app, event)
            global CubeROI SphereROI Web ROI_form Grad FOV Contour mode field_dif done Results figWeb figField figFieldRes figRes
            
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.Button_12.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Загрузка...";
            
            [file,path3] = uigetfile('*.txt');
            path3 = strcat(path3,file);
            fileID = fopen(path3,'r');
            
            if fileID ~= -1
                
                %data = table2array(readtable(path3));
                data = load(path3, "-ascii");
                
                Web.L = data(1,1);
                Web.R = data(2,1);
                Web.nfi = data(3,1);
                Web.nL = data(4,1);
                Web.axial = data(5,1);
                ROI_form = data(6,1);
                switch ROI_form
                    case 0
                        CubeROI.lx = data(7,1);
                        CubeROI.ly = data(8,1);
                        CubeROI.lz = data(9,1);
                        CubeROI.nx = data(10,1);
                        CubeROI.ny = data(11,1);
                        CubeROI.nz = data(12,1);
                        CubeROI.center(1) = data(13,1);
                        CubeROI.center(2) = data(14,1);
                        CubeROI.center(3) = data(15,1);
                        switch data(16,1)
                            case 0
                                Grad.type = "X";
                                app.Label_41.Text = "мТ/м";
                            case 1
                                Grad.type = "Y";
                                app.Label_41.Text = "мТ/м";
                            case 2
                                Grad.type = "Z";
                                app.Label_41.Text = "мТ/м";
                            case 3
                                Grad.type = "XY";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 4
                                Grad.type = "XZ";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 5
                                Grad.type = "YZ";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 6
                                Grad.type = "X2-Y2";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 7
                                Grad.type = "X2";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                        end
                        Grad.value = data(17,1);
                        Grad.boff = data(18,1);
                        Web.I = data(19:length(data(:)),1);
                    case 1
                        SphereROI.Rsp = data(9);
                        SphereROI.nR = data(10);
                        SphereROI.nksi = data(11);
                        SphereROI.ntetta = data(12);
                        SphereROI.center(1) = data(13);
                        SphereROI.center(2) = data(14);
                        SphereROI.center(3) = data(15);
                        switch data(16)
                            case 0
                                Grad.type = "X";
                                app.Label_41.Text = "мТ/м";
                            case 1
                                Grad.type = "Y";
                                app.Label_41.Text = "мТ/м";
                            case 2
                                Grad.type = "Z";
                                app.Label_41.Text = "мТ/м";
                            case 3
                                Grad.type = "XY";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 4
                                Grad.type = "XZ";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 5
                                Grad.type = "YZ";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 6
                                Grad.type = "X2-Y2";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                            case 7
                                Grad.type = "X2";
                                app.Label_41.Text = "мТ/м<sup>2</sup>";
                        end
                        Grad.value = data(17);
                        Grad.boff = data(18);
                        Web.I = data(19:length(data(:)),1);
                    case 2
                        len = data(7,1);
                        Grad.ROI = data(8:7+len,:);
                        Grad.B_target = data(8+len:7+2*len,1);
                        Web.I = data(8+2*len:length(data(:,1)),1);
                end
            
                % -------------------------------------------------------------------- % Отображение            
                
                app.REditField.Value = Web.R*1000;
                app.LEditField.Value = Web.L*1000;
                app.Spinner.Value = Web.nfi;
                app.Spinner_2.Value = Web.nL;
                if Web.axial
                    app.Button.Value = 1;
                else
                    app.Button_2.Value = 1;
                end
                
                switch ROI_form
                    case 0
                       app.TabGroup.SelectedTab = app.Tab;
                    case 1
                       app.TabGroup.SelectedTab = app.Tab_2;
                    case 2
                       app.TabGroup.SelectedTab = app.Tab_3;
                end
                
                app.Spinner_3.Value = CubeROI.nx;
                app.Spinner_4.Value = CubeROI.ny;
                app.Spinner_5.Value = CubeROI.nz;
                app.EditField.Value = CubeROI.lx*1000;
                app.EditField_2.Value = CubeROI.ly*1000;
                app.EditField_3.Value = CubeROI.lz*1000;
                app.EditField_4.Value = CubeROI.center(1)*1000;
                app.EditField_5.Value = CubeROI.center(2)*1000;
                app.EditField_6.Value = CubeROI.center(3)*1000;
                
                app.Spinner_6.Value = SphereROI.nR;
                app.Spinner_7.Value = SphereROI.nksi;
                app.Spinner_8.Value = SphereROI.ntetta;
                app.EditField_7.Value = SphereROI.Rsp*1000;
                app.EditField_10.Value = SphereROI.center(1)*1000;
                app.EditField_11.Value = SphereROI.center(2)*1000;
                app.EditField_12.Value = SphereROI.center(3)*1000;
                
                app.DropDown.Value = Grad.type;
                app.EditField_19.Value = Grad.value*1000;
                app.EditField_20.Value = Grad.boff*1000;
                
                % -------------------------------------------------------------------- % Подсчет
                
                % -------------------------------------------------------------------- % Сетка
               
                Web.Node = CreateNode(Web.R, Web.L, Web.nfi, Web.nL, Web.axial);
                Web.S = CreateS(Web.nfi*Web.nL, Web.nfi);
                
                FOV.FOV = CreateCubeROI(FOV.nx, FOV.ny, FOV.nz, FOV.lx, FOV.ly, FOV.lz, FOV.center);
                
                switch ROI_form
                    case 0
                        Grad.ROI = CreateCubeROI(CubeROI.nx, CubeROI.ny, CubeROI.nz, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center);
                        figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);
                    case 1
                        Grad.ROI = CreateSphereROI(SphereROI.nR, SphereROI.nksi, SphereROI.ntetta, SphereROI.Rsp, SphereROI.center);
                        figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);
                    case 2
                        figWeb = PointWebROI(Web.Node*1000, Web.S, Grad.ROI*1000, FOV.lx*1000, FOV.ly*1000, FOV.lz*1000, FOV.center*1000, figWeb);   % Исправить------------------------------------------
                end
                
                app.Label_59.Text = num2str(Web.nfi*Web.nL);
                app.Label_60.Text = num2str(length(Web.S));
                
                % -------------------------------------------------------------------- % Поле
                
                if ROI_form ~= 2
                    switch Grad.type
                        case "X"
                            Grad.B_target = Grad.value*Grad.ROI(:,1) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,1) + Grad.boff;
                        case "Y"
                            Grad.B_target = Grad.value*Grad.ROI(:,2) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,2) + Grad.boff;
                        case "Z"
                            Grad.B_target = Grad.value*Grad.ROI(:,3) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,3) + Grad.boff;
                        case "XY"
                            Grad.B_target = Grad.value*Grad.ROI(:,1).*Grad.ROI(:,2) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,1).*FOV.FOV(:,2) + Grad.boff;
                        case "XZ"
                            Grad.B_target = Grad.value*Grad.ROI(:,1).*Grad.ROI(:,3) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,1).*FOV.FOV(:,3) + Grad.boff;
                        case "YZ"
                            Grad.B_target = Grad.value*Grad.ROI(:,2).*Grad.ROI(:,3) + Grad.boff;
                            FOV.B_target = Grad.value*FOV.FOV(:,2).*FOV.FOV(:,3) + Grad.boff;
                        case "X2-Y2"
                            Grad.B_target = Grad.value*(Grad.ROI(:,1).*Grad.ROI(:,1) - Grad.ROI(:,2).*Grad.ROI(:,2)) + Grad.boff;
                            FOV.B_target = Grad.value*(FOV.FOV(:,1).*FOV.FOV(:,1) - FOV.FOV(:,2).*FOV.FOV(:,2)) + Grad.boff;
                        case "X2"
                            Grad.B_target = Grad.value*abs(Grad.ROI(:,1)).*Grad.ROI(:,1) + Grad.boff;
                            FOV.B_target = Grad.value*abs(FOV.FOV(:,1)).*FOV.FOV(:,1) + Grad.boff;
                    end
                else 
                    FOV.B_target = ones(length(FOV.FOV(:,1)),1);
                end
                
                switch ROI_form
                    case 0 
                        figField = PrintFieldCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figField);
                    case 1
                        figField = PrintFieldSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figField);
                    case 2 
                        figField = PrintFieldSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_target, FOV.level, FOV.plane, 0, SphereROI.center, figField);
                end 
                
                % -------------------------------------------------------------------- % Результат
                
                switch ROI_form
                    case 0
                        [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, CubeROI.nx*CubeROI.ny*CubeROI.nz, Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
                    case 1
                        [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, SphereROI.nR*SphereROI.nksi*SphereROI.ntetta, Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
                    case 2
                        [Grad.B_received, Grad.B_difference] = ReceivedField(Web.I, length(Grad.ROI(:,1)), Web.nfi*Web.nL, Web.S, Web.Node, Grad.ROI, Grad.B_target);
                end
                [FOV.B_received, FOV.B_difference] = ReceivedField(Web.I, FOV.nx*FOV.ny*FOV.nz, Web.nfi*Web.nL, Web.S, Web.Node, FOV.FOV, FOV.B_target);
                
                Krit = max(abs(Grad.B_difference)./max(Grad.B_target, [], 'all'), [], 'all')*100;
                FOV.B_difference = 100*(FOV.B_difference)./max(FOV.B_target, [], 'all');
                
                [Results.px, Results.py, Results.Fi, Results.Ll, Results.II, Contour.Red_3D, Contour.Blue_3D, Contour.Red_2D, Contour.Blue_2D, figRes] = PrintResults(Web.R, Web.L, Web.nfi, Web.nL, Web.axial, Web.I, Contour.Nc, Contour.offb, Contour.offr, Contour.zero, mode, figRes);
            
                if ~field_dif
                    switch ROI_form
                        case 0 
                            figFieldRes = PrintFieldCubeRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                        case 1
                            figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                        case 2 
                            figFieldRes = PrintFieldSphereRes(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_received, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                    end  
                else
                    switch ROI_form
                        case 0 
                            figFieldRes = PrintDiffCube(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, CubeROI.lx, CubeROI.ly, CubeROI.lz, CubeROI.center, figFieldRes);
                        case 1
                            figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, SphereROI.Rsp, SphereROI.center, figFieldRes);
                        case 2 
                            figFieldRes = PrintDiffSphere(FOV.lx, FOV.ly, FOV.lz, FOV.nx, FOV.ny, FOV.nz, FOV.center, FOV.B_difference, FOV.level, FOV.plane, 0, SphereROI.center, figFieldRes);
                    end      
                end
                app.Label_58.Text = strcat("Максимальное отклонение: ", num2str(Krit), " %");
                app.DropDown_4.Enable = "on";
                app.DropDown_4.Visible = "on";
                app.DropDown_5.Enable = "on";
                app.DropDown_5.Visible = "on";
                app.Label_58.Visible = "on";
                %app.Panel.Enable = "on";
                app.Panel_2.Enable = "on";
                %app.Panel_6.Enable = "on";
                app.Panel_3.Enable = "on";
                app.Panel_4.Enable = "on";
                app.Panel_5.Enable = "on";
                app.Button_3.Enable = "on";
                app.Button_4.Enable = "on";
                %app.Button_5.Enable = "on";
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_8.Enable = "on";
                app.EditField_26.Enable = "on";
                app.Button_9.Enable = "on";
                app.Button_12.Enable = "on";
                app.DropDown_4.Enable = "on";
                app.DropDown_5.Enable = "on";
                app.PushTool2.Enable = "on";
                app.DropDown.Enable = "off";
                app.EditField_19.Enable = "off";
                app.EditField_20.Enable = "off";
                app.PushTool.Enable = "off";
                done = 1;
            else 
                app.Lamp.Color = [0 1 0];
                app.Label_55.Text = "Готово!";
                app.Panel.Enable = "on";
                app.Panel_2.Enable = "on";
                app.Panel_6.Enable = "on";
                app.Panel_3.Enable = "on";
                app.Panel_4.Enable = "on";
                app.Panel_5.Enable = "on";
                app.Button_3.Enable = "on";
                %app.Button_4.Enable = "on";
                %app.Button_5.Enable = "on";
                %app.Button_6.Enable = "on";
                %app.Button_7.Enable = "on";
                app.Button_8.Enable = "on";
                app.EditField_26.Enable = "on";
                %app.Button_9.Enable = "on";
                app.DropDown_4.Enable = "on";
                app.DropDown_5.Enable = "on";
                app.PushTool.Enable = "on";
                %app.PushTool2.Enable = "on";
                %app.DropDown.Enable = "off";
                %app.EditField_19.Enable = "off";
                %app.EditField_20.Enable = "off";
            end
        end

        % Button pushed function: Button_7
        function Reset(app, event)
            global Web done Grad ROI_form ROI_selected Field_selected
             
            done = 0;
            Web.I = [];
            Grad.ROI = [];
            Grad.B_target = [];
            ROI_selected = 0;
            Field_selected = 0;
            
            close all
%             cla(app.UIAxes_3, 'reset')
%             cla(app.UIAxes_4, 'reset')
%             cla(app.UIAxes, 'reset')
%             cla(app.UIAxes_2, 'reset')
%             app.UIAxes_3.Visible = "off";
%             app.UIAxes_4.Visible = "off";
%             app.UIAxes.Visible = "off";
%             app.UIAxes_2.Visible = "off";
                
            app.Label_59.Text = "...........";
            app.Label_60.Text = "...........";
            app.Panel.Enable = "on";
            app.Panel_6.Enable = "on";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.DropDown.Enable = "on";
            app.EditField_19.Enable = "on";
            app.EditField_20.Enable = "on";
            app.Button_9.Enable = "off";
            app.Button_12.Enable = "off";
            app.PushTool2.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_4.Visible = "off";
            app.DropDown_5.Enable = "off";
            app.DropDown_5.Visible = "off";
            app.Label_58.Visible = "off";
            app.Label_57.Text = "0 c";
            app.PushTool.Enable = "on";
            
            if ROI_form == 2
                app.Label_64.Text = "Не выбрано...";
                app.Label_62.Text = "Не выбрано...";
                app.Button_11.Enable = "off";
                app.Button_3.Enable = "off";
                app.DropDown.Enable = "off";
                app.EditField_19.Enable = "off";
                app.EditField_20.Enable = "off";
            end
        end

        % Button pushed function: Button_10
        function LoadROI(app, event)
            global Grad ROI_selected
            [file,path3] = uigetfile('*.txt');
            path3 = strcat(path3,file);
            fileID = fopen(path3,'r');
            
            if fileID ~= -1

               data = load(path3, "-ascii");
               
                if length(data(1,:)) == 3
                    Grad.ROI = data;
                    %Grad.ROI
                    app.Label_64.Text = "Подходит ✔";
                    ROI_selected = 1;
                    app.Button_11.Enable = "on";
                    app.Button_3.Enable = "on";
                else 
                    app.Label_64.Text = "Не подходит ❌";
                    ROI_selected = 0;
                    app.Button_11.Enable = "off";
                    app.Button_3.Enable = "off";
                end
                
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            global Grad FOV Field_selected
            [file,path3] = uigetfile('*.txt');
            path3 = strcat(path3,file);
            fileID = fopen(path3,'r');
            
            if fileID ~= -1

                data = load(path3, "-ascii");
                
                if length(data(:,1)) == length(Grad.ROI(:,3))
                    Grad.B_target = data(:,1);
                    %Grad.B_target
                    FOV.B_target = ones(length(Grad.ROI(:,3)),1);
                    app.Label_62.Text = "Подходит ✔";
                    Field_selected = 1;
                    %app.Button_5.Enable = "on";
                    %app.Button_4.Enable = "on";
                else 
                    app.Label_62.Text = "Не подходит ❌";
                    Field_selected = 0;
                    app.Button_5.Enable = "off";
                    app.Button_4.Enable = "off";
                end
                
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            global Web Contour done Grad Optimisation path FOV
            app.Panel.Enable = "off";
            app.Panel_2.Enable = "off";
            app.Panel_6.Enable = "off";
            app.Panel_3.Enable = "off";
            app.Panel_4.Enable = "off";
            app.Panel_5.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.Button_8.Enable = "off";
            app.EditField_26.Enable = "off";
            app.Button_9.Enable = "off";
            app.Button_12.Enable = "off";
            app.DropDown_4.Enable = "off";
            app.DropDown_5.Enable = "off";
            app.PushTool.Enable = "off";
            app.PushTool2.Enable = "off";
            app.Lamp.Color = [0.93,0.69,0.13];
            app.Label_55.Text = "Сохранение...";
            pause(0.1)
                       
            str = strcat("B_received_",num2str(Web.L*1000),"_",num2str(Web.R*1000),"_",num2str(Web.nfi),"_",num2str(Web.nL),"_",num2str(Web.axial),".txt");
            str = strcat(path,str);
            delete(str);
            
            nx = FOV.nx;
            ny = FOV.ny;
            nz = FOV.nz;
            lx = FOV.lx;
            ly = FOV.ly;
            lz = FOV.lz;
            cx = FOV.center(1);
            cy = FOV.center(2);
            cz = FOV.center(3); 
            B = FOV.B_received;
                        
            save(str,"nx","ny","nz","lx","ly","lz","cx","cy","cz","B","-ascii")
            
            app.Lamp.Color = [0 1 0];
            app.Label_55.Text = "Готово!";
            if ~done
                app.Panel.Enable = "on";
                app.Panel_6.Enable = "on";
            end
            app.Panel_2.Enable = "on";
            app.Panel_3.Enable = "on";
            app.Panel_4.Enable = "on";
            app.Panel_5.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            if done
                app.Button_6.Enable = "on";
                app.Button_7.Enable = "on";
                app.Button_5.Enable = "off";
                app.Button_9.Enable = "on";
                app.Button_12.Enable = "on";
                app.PushTool2.Enable = "on";
            else 
                app.PushTool.Enable = "on";
            end
            app.Button_8.Enable = "on";
            app.EditField_26.Enable = "on";
            app.DropDown_4.Enable = "on";
            app.DropDown_5.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @LoadData, true);
            app.PushTool.Icon = 'load4.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @SaveData, true);
            app.PushTool2.Enable = 'off';
            app.PushTool2.Icon = 'save.png';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Настройки сетки';
            app.REditFieldLabel = uilabel(app.Panel);
            app.REditFieldLabel.Text = 'Радиус катушки R';
            app.REditField = uieditfield(app.Panel, 'numeric');
            app.REditField.Limits = [1 Inf];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Value = 1;
            app.LEditFieldLabel = uilabel(app.Panel);
            app.LEditFieldLabel.Text = 'Длина катушки L';
            app.LEditField = uieditfield(app.Panel, 'numeric');
            app.LEditField.Limits = [1 Inf];
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 1;
            app.Label = uilabel(app.Panel);
            app.Label.Text = {'Кол-во точек'; 'по окружности'};
            app.Spinner = uispinner(app.Panel);
            app.Spinner.Limits = [3 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 3;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Кол-во точек в длину';
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_2.Limits = [3 Inf];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 3;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Магнитная система';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Аксиальная';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Хальбаха';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'Wb.png';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'мм';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = 'мм';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = 'Узлов:';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = 'Элементов:';
            app.Label_59 = uilabel(app.Panel);
            app.Label_59.Text = '...........';
            app.Label_60 = uilabel(app.Panel);
            app.Label_60.Text = '...........';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Область просмотра';
            app.Label_29 = uilabel(app.Panel_2);
            app.Label_29.Text = 'мм';
            app.Label_30 = uilabel(app.Panel_2);
            app.Label_30.Text = 'мм';
            app.Label_31 = uilabel(app.Panel_2);
            app.Label_31.Text = 'Положение';
            app.Label_32 = uilabel(app.Panel_2);
            app.Label_32.Text = 'Размер';
            app.ZLabel_2 = uilabel(app.Panel_2);
            app.ZLabel_2.Text = 'Z';
            app.YLabel_2 = uilabel(app.Panel_2);
            app.YLabel_2.Text = 'Y';
            app.XLabel_2 = uilabel(app.Panel_2);
            app.XLabel_2.Text = 'X';
            app.Label_33 = uilabel(app.Panel_2);
            app.Label_33.Text = 'мм';
            app.Label_34 = uilabel(app.Panel_2);
            app.Label_34.Text = 'мм';
            app.Label_35 = uilabel(app.Panel_2);
            app.Label_35.Text = 'мм';
            app.Label_36 = uilabel(app.Panel_2);
            app.Label_36.Text = 'Точки';
            app.Spinner_9 = uispinner(app.Panel_2);
            app.Spinner_9.Limits = [3 Inf];
            app.Spinner_9.ValueChangedFcn = createCallbackFcn(app, @Spinner_9ValueChanged, true);
            app.Spinner_9.Value = 3;
            app.Spinner_10 = uispinner(app.Panel_2);
            app.Spinner_10.Limits = [3 Inf];
            app.Spinner_10.ValueChangedFcn = createCallbackFcn(app, @Spinner_10ValueChanged, true);
            app.Spinner_10.Value = 3;
            app.Spinner_11 = uispinner(app.Panel_2);
            app.Spinner_11.Limits = [3 Inf];
            app.Spinner_11.ValueChangedFcn = createCallbackFcn(app, @Spinner_11ValueChanged, true);
            app.Spinner_11.Value = 3;
            app.EditField_13 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_13.Limits = [1 Inf];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Value = 1;
            app.Label_37 = uilabel(app.Panel_2);
            app.Label_37.Text = 'мм';
            app.EditField_14 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_14.Limits = [1 Inf];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Value = 1;
            app.EditField_15 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_15.Limits = [1 Inf];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Value = 1;
            app.EditField_16 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_17 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.EditField_18 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Настройки поля';
            app.Label_38 = uilabel(app.Panel_3);
            app.Label_38.Text = {'Величина'; 'градиента'};
            app.EditField_19 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_19.ValueChangedFcn = createCallbackFcn(app, @EditField_19ValueChanged, true);
            app.Label_39 = uilabel(app.Panel_3);
            app.Label_39.Text = 'Смещение';
            app.EditField_20 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_20.ValueChangedFcn = createCallbackFcn(app, @EditField_20ValueChanged, true);
            app.Label_40 = uilabel(app.Panel_3);
            app.Label_40.Text = 'мТ';
            app.Label_41 = uilabel(app.Panel_3);
            app.Label_41.Interpreter = 'html';
            app.Label_41.Text = 'мТ/м';
            app.Label_42 = uilabel(app.Panel_3);
            app.Label_42.Text = 'Тип градиента';
            app.DropDown = uidropdown(app.Panel_3);
            app.DropDown.Items = {'X', 'Y', 'Z', 'XY', 'XZ', 'YZ', 'X2-Y2', 'X2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'X';
            app.Label_43 = uilabel(app.Panel_3);
            app.Label_43.Text = 'Плоскость';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'XY', 'XZ', 'YZ'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'XY';
            app.Label_44 = uilabel(app.Panel_3);
            app.Label_44.Text = 'Уровень';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.Label_45 = uilabel(app.Panel_3);
            app.Label_45.Text = 'мм';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Оптимизация';
            app.Image2 = uiimage(app.Panel_4);
            app.Image2.ImageSource = 'F.png';
            app.AlpfaEditFieldLabel = uilabel(app.Panel_4);
            app.AlpfaEditFieldLabel.Text = 'Alpfa';
            app.AlpfaEditField = uieditfield(app.Panel_4, 'numeric');
            app.AlpfaEditField.Limits = [0 Inf];
            app.AlpfaEditField.ValueChangedFcn = createCallbackFcn(app, @AlpfaEditFieldValueChanged, true);
            app.BetaEditFieldLabel = uilabel(app.Panel_4);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.Panel_4, 'numeric');
            app.BetaEditField.Limits = [0 Inf];
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.Label_46 = uilabel(app.Panel_4);
            app.Label_46.Text = {'Удельное '; 'сопротивление'};
            app.EditField_21 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_21.Limits = [0 Inf];
            app.EditField_21.ValueChangedFcn = createCallbackFcn(app, @EditField_21ValueChanged, true);
            app.Label_47 = uilabel(app.Panel_4);
            app.Label_47.Text = {'Толщина'; 'проводника'};
            app.EditField_22 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_22.Limits = [0.01 Inf];
            app.EditField_22.ValueChangedFcn = createCallbackFcn(app, @EditField_22ValueChanged, true);
            app.EditField_22.Value = 0.01;
            app.Label_48 = uilabel(app.Panel_4);
            app.Label_48.Text = 'мм';
            app.sup2supLabel = uilabel(app.Panel_4);
            app.sup2supLabel.Interpreter = 'html';
            app.sup2supLabel.Text = 'мм<sup>2</sup>Ом/м';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Title = 'Настройка контура';
            app.Label_49 = uilabel(app.Panel_5);
            app.Label_49.Text = 'Количество витков';
            app.Spinner_12 = uispinner(app.Panel_5);
            app.Spinner_12.Limits = [1 Inf];
            app.Spinner_12.ValueChangedFcn = createCallbackFcn(app, @Spinner_12ValueChanged, true);
            app.Spinner_12.Value = 1;
            app.Label_50 = uilabel(app.Panel_5);
            app.Label_50.Text = 'Нулевой уровень';
            app.EditField_23 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_23.ValueChangedFcn = createCallbackFcn(app, @EditField_23ValueChanged, true);
            app.Label_51 = uilabel(app.Panel_5);
            app.Label_51.Text = 'Ширина синих витков';
            app.EditField_24 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_24.Limits = [0 Inf];
            app.EditField_24.ValueChangedFcn = createCallbackFcn(app, @EditField_24ValueChanged, true);
            app.Label_52 = uilabel(app.Panel_5);
            app.Label_52.Text = 'Ширина красных витков';
            app.EditField_25 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_25.Limits = [0 Inf];
            app.EditField_25.ValueChangedFcn = createCallbackFcn(app, @EditField_25ValueChanged, true);
            app.Label_53 = uilabel(app.Panel_5);
            app.Label_53.Text = '%';
            app.Label_54 = uilabel(app.Panel_5);
            app.Label_54.Text = '%';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @NodeSROIFOV, true);
            app.Button_3.Text = 'Построить сетку';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Field, true);
            app.Button_4.Enable = 'off';
            app.Button_4.Text = 'Построить поле';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Calculation, true);
            app.Button_5.Enable = 'off';
            app.Button_5.Text = 'Расчет';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @SettingsContour, true);
            app.Button_6.Enable = 'off';
            app.Button_6.Text = {'Применить'; 'настройки контура'};
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.Button_7.Enable = 'off';
            app.Button_7.Text = 'Сброс';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_55 = uilabel(app.UIFigure);
            app.Label_55.Text = 'Готово!';
            app.Label_56 = uilabel(app.UIFigure);
            app.Label_56.Text = 'Затраченное время:';
            app.Label_57 = uilabel(app.UIFigure);
            app.Label_57.Text = '0 с';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_4.Items = {'Намотка 2D', 'Намотка 3D', 'Потоковая функция', 'Потоковая функция + grad'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @ModeChange, true);
            app.DropDown_4.Visible = 'off';
            app.DropDown_4.Value = 'Намотка 2D';
            app.Label_58 = uilabel(app.UIFigure);
            app.Label_58.Visible = 'off';
            app.Label_58.Text = 'Максимальное отклонение:';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ChangeFolder, true);
            app.Button_8.Text = 'Выбор папки';
            app.EditField_26 = uieditfield(app.UIFigure, 'text');
            app.EditField_26.Editable = 'off';
            app.Panel_6 = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel_6);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Куб';
            app.Label_9 = uilabel(app.Tab);
            app.Label_9.Text = 'мм';
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = 'мм';
            app.Label_11 = uilabel(app.Tab);
            app.Label_11.Text = 'Положение';
            app.Label_12 = uilabel(app.Tab);
            app.Label_12.Text = 'Размер';
            app.ZLabel = uilabel(app.Tab);
            app.ZLabel.Text = 'Z';
            app.YLabel = uilabel(app.Tab);
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.Tab);
            app.XLabel.Text = 'X';
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = 'мм';
            app.Label_14 = uilabel(app.Tab);
            app.Label_14.Text = 'мм';
            app.Label_15 = uilabel(app.Tab);
            app.Label_15.Text = 'мм';
            app.Label_16 = uilabel(app.Tab);
            app.Label_16.Text = 'Точки';
            app.Spinner_3 = uispinner(app.Tab);
            app.Spinner_3.Limits = [3 Inf];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Spinner_3.Value = 3;
            app.Spinner_4 = uispinner(app.Tab);
            app.Spinner_4.Limits = [3 Inf];
            app.Spinner_4.ValueChangedFcn = createCallbackFcn(app, @Spinner_4ValueChanged, true);
            app.Spinner_4.Value = 3;
            app.Spinner_5 = uispinner(app.Tab);
            app.Spinner_5.Limits = [3 Inf];
            app.Spinner_5.ValueChangedFcn = createCallbackFcn(app, @Spinner_5ValueChanged, true);
            app.Spinner_5.Value = 3;
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = 'мм';
            app.EditField_2 = uieditfield(app.Tab, 'numeric');
            app.EditField_2.Limits = [1 Inf];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.EditField_3 = uieditfield(app.Tab, 'numeric');
            app.EditField_3.Limits = [1 Inf];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.EditField_4 = uieditfield(app.Tab, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.Tab, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.Tab, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.ZLabel_4 = uilabel(app.Tab);
            app.ZLabel_4.Text = 'Z';
            app.YLabel_4 = uilabel(app.Tab);
            app.YLabel_4.Text = 'Y';
            app.XLabel_4 = uilabel(app.Tab);
            app.XLabel_4.Text = 'X';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Сфера';
            app.Label_18 = uilabel(app.Tab_2);
            app.Label_18.Text = 'мм';
            app.Label_19 = uilabel(app.Tab_2);
            app.Label_19.Text = 'мм';
            app.Label_20 = uilabel(app.Tab_2);
            app.Label_20.Text = 'Положение';
            app.Label_21 = uilabel(app.Tab_2);
            app.Label_21.Text = 'Размер';
            app.Label_27 = uilabel(app.Tab_2);
            app.Label_27.Text = 'φ';
            app.RLabel = uilabel(app.Tab_2);
            app.RLabel.Text = 'R';
            app.Label_23 = uilabel(app.Tab_2);
            app.Label_23.Text = 'мм';
            app.Label_24 = uilabel(app.Tab_2);
            app.Label_24.Text = 'мм';
            app.Label_25 = uilabel(app.Tab_2);
            app.Label_25.Text = 'Точки';
            app.Spinner_6 = uispinner(app.Tab_2);
            app.Spinner_6.Limits = [3 Inf];
            app.Spinner_6.ValueChangedFcn = createCallbackFcn(app, @Spinner_6ValueChanged, true);
            app.Spinner_6.Value = 3;
            app.Spinner_7 = uispinner(app.Tab_2);
            app.Spinner_7.Limits = [3 Inf];
            app.Spinner_7.ValueChangedFcn = createCallbackFcn(app, @Spinner_7ValueChanged, true);
            app.Spinner_7.Value = 3;
            app.Spinner_8 = uispinner(app.Tab_2);
            app.Spinner_8.Limits = [3 Inf];
            app.Spinner_8.ValueChangedFcn = createCallbackFcn(app, @Spinner_8ValueChanged, true);
            app.Spinner_8.Value = 3;
            app.EditField_7 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_7.Limits = [1 Inf];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1;
            app.EditField_10 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.Label_28 = uilabel(app.Tab_2);
            app.Label_28.Text = 'θ';
            app.ZLabel_3 = uilabel(app.Tab_2);
            app.ZLabel_3.Text = 'Z';
            app.YLabel_3 = uilabel(app.Tab_2);
            app.YLabel_3.Text = 'Y';
            app.XLabel_3 = uilabel(app.Tab_2);
            app.XLabel_3.Text = 'X';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Произвольно';
            app.Button_10 = uibutton(app.Tab_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @LoadROI, true);
            app.Button_10.Text = 'Выбор файла';
            app.ROILabel = uilabel(app.Tab_3);
            app.ROILabel.Text = 'Произвольный ROI';
            app.Label_63 = uilabel(app.Tab_3);
            app.Label_63.Text = 'Целевое поле';
            app.Button_11 = uibutton(app.Tab_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = 'Выбор файла';
            app.Label_62 = uilabel(app.Tab_3);
            app.Label_62.Text = 'Не выбрано...';
            app.Label_64 = uilabel(app.Tab_3);
            app.Label_64.Text = 'Не выбрано...';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @SavePicture, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Text = 'Сохранить картинку';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.DropDown_5.Items = {'Полученное поле', 'Разность'};
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DifChange, true);
            app.DropDown_5.Visible = 'off';
            app.DropDown_5.Value = 'Полученное поле';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Enable = 'off';
            app.Button_12.Text = 'Сохранить поле';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end